classdef BitPlayer_2P < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_2PUIFigure       matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        RemoveSacTrials            matlab.ui.control.StateButton
        n_bootstrp                 matlab.ui.control.NumericEditField
        NumbootstrpEditFieldLabel  matlab.ui.control.Label
        Get_FVsamptButton          matlab.ui.control.StateButton
        Get_F0Button               matlab.ui.control.StateButton
        FVsampt                    matlab.ui.control.NumericEditField
        FVsamptLabel               matlab.ui.control.Label
        SaveFileName               matlab.ui.control.EditField
        ImageSize                  matlab.ui.control.NumericEditField
        ImagesizeLabel             matlab.ui.control.Label
        newbootstrapCheckBox       matlab.ui.control.CheckBox
        Saveas                     matlab.ui.control.Button
        DebugButton                matlab.ui.control.Button
        FileName                   matlab.ui.control.Label
        Bootstrap                  matlab.ui.control.Button
        ShowROIMap                 matlab.ui.control.Button
        ShowTuningSelected         matlab.ui.control.Button
        ShowColormap               matlab.ui.control.Button
        ShowSelected               matlab.ui.control.Button
        AveragedbyStim             matlab.ui.control.Button
        Zscore                     matlab.ui.control.StateButton
        Offset                     matlab.ui.control.StateButton
        Lowcutfilter               matlab.ui.control.StateButton
        Detrend                    matlab.ui.control.StateButton
        ApplyChanges               matlab.ui.control.StateButton
        F0frames                   matlab.ui.control.EditField
        F0framesEditFieldLabel     matlab.ui.control.Label
        HzEditField                matlab.ui.control.NumericEditField
        HzEditFieldLabel           matlab.ui.control.Label
        DeselectROIs               matlab.ui.control.EditField
        DeselectEditFieldLabel     matlab.ui.control.Label
        SelectROIs                 matlab.ui.control.EditField
        SelectLabel                matlab.ui.control.Label
        n_ROI                      matlab.ui.control.NumericEditField
        ROIEditFieldLabel          matlab.ui.control.Label
        ROI_Minus                  matlab.ui.control.Button
        ROI_Plus                   matlab.ui.control.Button
        Load2P                     matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % BitPlayer for Analysis. GUI for two-photon data.
    
    % 2023/03/03 version 0.1.2: Use MATLAB 2022a (Ubuntu 20.0.4 LTS)
    % 2023/02/15 version 0.1.1: Created by MATLAB 2020b

    properties (Access = private)
        BitPlayer_Analysys %Main app object
    end
    
    properties (Access = public)
        mainvar
        SaveData
        SaveTimestamps
        recobj
        sobj
        ParamsSave
        imgobj
        ax1_p
        ax2_p
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Sharing variables with BitPlayer_Analysis.mlapp
            app.BitPlayer_Analysys = mainapp;
            
            % Set variables
            app.mainvar = mainapp.mainvar;
            app.SaveData = mainapp.SaveData;
            app.SaveTimestamps = mainapp.SaveTimestamps;
            app.recobj = mainapp.recobj;
            app.sobj = mainapp.sobj;
            app.ParamsSave = mainapp.ParamsSave;
            app.imgobj = mainapp.imgobj;

            % Path setting
            mypath = '/home/lattice/Research/BitPlayer/Analysis/';            
            subfolders = {'GUI_2P', 'StimSpecific', 'Utils'};
            for i = 1:length(subfolders)
                p = genpath([mypath, subfolders{i}]);
                addpath(p)
            end
            
            %%%%%%%%%%%% %%%%%%%%%% %%%%%%%%%%
            % Check parameters
                        
            if ~isfield(app.imgobj, 'FVsampt')
                app.imgobj.FVsampt = app.FVsampt.Value;
            end
            
            
            %if isfield(app.imgobj, 'F'), Plot dFF
            if isfield(app.imgobj, 'F') && isfield(app.mainvar, 'dirname_2p')
                % Imaging data is already loaded
                app.SaveFileName.Value = app.mainvar.fname_daq;
                app.FileName.Text = {['File: ', app.mainvar.dirname_2p],...
                    app.mainvar.fname_2p};
                
                % Check F0
                if isfield(app.imgobj, 'f0')
                    if app.imgobj.f0(1) == app.imgobj.f0(end)
                        f0_txt = num2str(app.imgobj.f0(1));
                    else
                        f0_txt = [num2str(app.imgobj.f0(1)), ':',...
                            num2str(app.imgobj.f0(end))];
                    end
                    app.F0frames.Value = f0_txt;
                    app.Get_F0Button.Value = 1;
                    app.Get_F0Button.BackgroundColor = [1, 0.81, 0.81];
                    app.F0frames.BackgroundColor = [1, 0.81, 0.81];
                end
                
                % Check dFF modulation
                if ~isfield(app.mainvar, 'Detrend')
                    % Temporally
                    app.mainvar.Detrend = 0;
                    app.mainvar.Lowcutfilter = 0;
                    app.mainvar.Offset = 0;
                    app.mainvar.Zscore = 0;
                    app.mainvar.ApplydFF = 0;
                end
                
                applydff = 0;

                if app.mainvar.Detrend
                    app.Detrend.Value = 1;
                    Change_button_color(app.Detrend, 1);
                    applydff = 1;
                end
                
                
                if isfield(app.mainvar, 'Lowcutfilter') &&...
                        app.mainvar.Lowcutfilter
                    app.Lowcutfilter.Value = 1;
                    Change_button_color(app.Lowcutfilter, 1);
                    applydff = 1;
                end
                
                if app.mainvar.Zscore
                    app.Zscore.Value = 1;
                    Change_button_color(app.Zscore, 1);
                    applydff = 1;
                end
                
                if app.mainvar.Offset
                    app.Offset.Value = 1;
                    Change_button_color(app.Offset, 1);
                    applydff = 1;
                end

                if applydff
                    app.mainvar.ApplydFF = 1;
                    app.ApplyChanges.Value = 1;
                    Change_button_color(app.ApplyChanges, 1)
                end
                
                if ~isfield(app.imgobj, 'selected_ROIs')
                    app.imgobj.selected_ROIs = 1;
                end

                if isfield(app.imgobj, 'bstrpDone') && app.imgobj.bstrpDone
                    app.Bootstrap.BackgroundColor = [1, 0.81, 0.81];
                end
                % Plot ROI#1
                
                Plot_stim_area(app);
                Plot_dFF_next(app);

                if ~isempty(app.imgobj.selected_ROIs)
                    app.SelectROIs.Value = num2str(app.imgobj.selected_ROIs);
                    Plot_dFF_selected(app)
                end
                
                % Check loading metadata
                if isfield(app.mainvar, 'dirname_meta')
                    app.Get_FVsamptButton.Value = 1;
                    app.FVsampt.BackgroundColor = [1, 0.81, 0.81];
                    app.Get_FVsamptButton.BackgroundColor = [1, 0.81, 0.81];
                    app.ImageSize.BackgroundColor = [1, 0.81, 0.81];
                end

                app.AveragedbyStim.Enable='on';

                if isfield(app.imgobj, 'dFF_stim_average')
                    app.Bootstrap.Enable = 'on';
                    app.newbootstrapCheckBox.Enable = 'on';
                    app.ShowColormap.Enable = 'on';
                    app.ShowSelected.Enable = 'on';
                    app.ShowTuningSelected.Enable = 'on';
                    app.ShowROIMap.Enable = 'on';
                end
            end
        end

        % Button pushed function: Load2P
        function Load2PPushed(app, event)
            Open_load2P(app)
            Plot_stim_area(app)
            Plot_dFF_next(app)
            app.AveragedbyStim.Enable = 'on';
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            assignin('base', "mainvar", app.mainvar)
            assignin('base', "SaveData", app.SaveData)
            assignin('base', "SaveTimestamps", app.SaveTimestamps)
            assignin('base', "recobj", app.recobj)
            assignin('base', "sobj", app.sobj)
            assignin('base', "ParamsSave", app.ParamsSave)
            assignin('base', "imgobj", app.imgobj)
        end

        % Close request function: BitPlayer_2PUIFigure
        function BitPlayer_2PUIFigureCloseRequest(app, event)
            % Enable the Open2P button in main app
            app.BitPlayer_Analysys.Open2PWindowButton.Enable = 'on';
            app.BitPlayer_Analysys.Open2PWindowButton.Value = 0;
            
            % Update variables in main app
            app.BitPlayer_Analysys.imgobj = app.imgobj;
            app.BitPlayer_Analysys.sobj = app.sobj;
            app.BitPlayer_Analysys.ParamsSave = app.ParamsSave;
            app.BitPlayer_Analysys.mainvar = app.mainvar;
            
            disp('Close 2P App');
            pause(0.5);
            % Delete BitPlayer_2P App
            delete(app)
            
        end

        % Button pushed function: Saveas
        function SaveasPushed(app, event)
            Save_params2mat(app, '2P');
            app.SaveFileName.BackgroundColor = [1, 1, 1];

            %Update file name in the main GUI
            app.BitPlayer_Analysys.FileName.Text =...
                ['File Name: ', app.mainvar.fname_daq];
            
            app.BitPlayer_Analysys.SaveFileName.Value =...
                app.mainvar.fname_daq;
        end

        % Value changed function: SaveFileName
        function SaveFileNameValueChanged(app, event)
            value = app.SaveFileName.Value;
            if ~strcmp(value, app.mainvar.fname_daq)
                app.SaveFileName.BackgroundColor = [1, 0.81, 0.81];
            else
                app.SaveFileName.BackgroundColor = [1, 1, 1];
            end
        end

        % Value changed function: Get_FVsamptButton
        function Get_FVsamptButtonValueChanged(app, event)
            value = app.Get_FVsamptButton.Value;
            if value == 1
                if isfield(app.mainvar, 'dirname_meta')
                    d = app.mainvar.dirname_meta;
                else
                    %mouse = app.mainvar.mouse;
                    date = app.mainvar.date;
                    d = '/mnt/SSD1_Work/s2p_working';
                    %d = fullfile(d, date, mouse);
                    d = fullfile(d, date);

                    %d = app.mainvar.dirname_daq;
                end
                [app.imgobj, ~, d] = Get_FVsampt_v2(app.imgobj, d);

                % Update plot
                Plot_stim_area(app)
                Plot_dFF_next(app)
                
                app.mainvar.dirname_meta = d;
                app.FVsampt.Value = app.imgobj.FVsampt;
                app.ImageSize.Value = app.imgobj.imgsz(1);
                
                app.FVsampt.BackgroundColor = [1, 0.81, 0.81];
                app.Get_FVsamptButton.BackgroundColor = [1, 0.81, 0.81];
                app.ImageSize.BackgroundColor = [1, 0.81, 0.81];
            else
                % Reset
                app.FVsampt.BackgroundColor = [0.96,0.96,0.96];
                app.Get_FVsamptButton.BackgroundColor = [0.96,0.96,0.96];
                app.ImageSize.BackgroundColor = [0.96,0.96,0.96];
            end
            
        end

        % Value changed function: Get_F0Button
        function Get_F0ButtonValueChanged(app, event)
            value = app.Get_F0Button.Value;
            if value
                app.imgobj = Get_F0(app);
                if app.imgobj.f0(end) <= 1
                    app.imgobj.f0 = 1;
                    f0_txt = '1';
                else
                    f0_txt = ['1:', num2str(app.imgobj.f0(end))];
                end
                app.F0frames.Value = f0_txt;
                app.Get_F0Button.BackgroundColor = [1, 0.81, 0.81];
                app.F0frames.BackgroundColor = [1, 0.81, 0.81];
            else
                % Reset
                app.Get_F0Button.BackgroundColor = [0.96,0.96,0.96];
                app.F0frames.BackgroundColor = [1,1,1];
            end
            
            % (Re)Calculate dFF
            
        end

        % Value changed function: FVsampt
        function FVsamptValueChanged(app, event)
            fvt = app.FVsampt.Value;
            app.imgobj.FVsampt = fvt;
            app.FVsampt.BackgroundColor = [0.81, 0.81, 1];
            
            %Updage plot
            [FVflames, ~] = size(F);
            app.imgobj.FVt = 0:fvt:fvt*(FVflames - 1);
            Plot_dFF_next(app);
        end

        % Value changed function: ImageSize
        function ImageSizeValueChanged(app, event)
            value = app.ImageSize.Value;
            app.imgobj.imgsz = [value, value];
            app.ImageSize.BackgroundColor = [0.81, 0.81, 1];
        end

        % Value changed function: F0frames
        function F0framesValueChanged(app, event)
            value = app.F0frames.Value;
            app.imgobj.f0 = str2num(value); %#ok<*ST2NM> 
            app.F0frames.BackgroundColor = [0.81, 0.81, 1];
            
        end

        % Button pushed function: ROI_Plus
        function ROI_PlusButtonPushed(app, event)
            n = app.n_ROI.Value;
            if n < app.imgobj.Num_ROIs
                app.n_ROI.Value = n + 1;
                Plot_dFF_next(app);
            else
                disp('No more ROIs.')
            end
        end

        % Button pushed function: ROI_Minus
        function ROI_MinusButtonPushed(app, event)
            n = app.n_ROI.Value;
            if n > 1
                app.n_ROI.Value = n - 1;
                Plot_dFF_next(app);
            else
                disp('This is the first ROI.')
            end
        end

        % Value changed function: n_ROI
        function n_ROIValueChanged(app, event)
            n = app.n_ROI.Value;
            if n < 1 || n > app.imgobj.Num_ROIs
                errordlg('Out of range!')
                app.n_ROI.Value = 1;
            end
            Plot_dFF_next(app);
        end

        % Value changed function: ApplyChanges
        function ApplyChangesValueChanged(app, event)
            value = app.ApplyChanges.Value;
            Change_button_color(app.ApplyChanges, value);
            
            if value
                Apply_change_dFF(app);
                app.mainvar.ApplydFF = 1;
            else
                % Reset dFF
                F = app.imgobj.F;
                F0 = mean(F(app.imgobj.f0));
                app.imgobj.dFF = (F-F0)/F0;
                app.mainvar.ApplydFF = 0;
            end
            Plot_dFF_next(app);
            if ~isempty(app.SelectROIs.Value)
                Plot_dFF_selected(app);
            end
        end

        % Value changed function: Zscore
        function ZscoreValueChanged(app, event)
            value = app.Zscore.Value;
            Change_button_color(app.Zscore, value);
            if length(app.imgobj.f0) < 10
                app.Zscore.Value = 0;
                errordlg('Not enogh F0 frames.')
            end
            
            if value
                app.mainvar.Zscore = 1;
            else
                app.mainvar.Zscore = 0;
            end
            
        end

        % Value changed function: Detrend
        function DetrendValueChanged(app, event)
            value = app.Detrend.Value;
            Change_button_color(app.Detrend, value);
            if value
                app.mainvar.Detrend = 1;
            else
                app.mainvar.Detrend = 0;
            end
        end

        % Value changed function: Lowcutfilter
        function LowcutfilterValueChanged(app, event)
            value = app.Lowcutfilter.Value;
            Change_button_color(app.Lowcutfilter, value);
            if value
                app.mainvar.Lowcutfilter = 1;
            else
                app.mainvar.Lowcutfilter = 0;
            end
        end

        % Value changed function: Offset
        function OffsetValueChanged(app, event)
            value = app.Offset.Value;
            Change_button_color(app.Offset, value);
            if value
                app.mainvar.Offset = 1;
            else
                app.mainvar.Offset = 0;
            end
        end

        % Button pushed function: AveragedbyStim
        function AveragedbyStimButtonPushed(app, event)
            if isfield(app.sobj, 'Stim_valiation_type') &&...
                    strcmp(app.sobj.Stim_valiation_type, 'Free')

                %FreeDirection for MovingBar, FreePosition for Finemapping
                app.imgobj = Get_timecoruse_for_freestim(app);
            else
                Get_Trial_Averages_for_timecourse(app);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%
            %                      %
            % Calculate DSI/OSI    %
            %                      %
            %%%%%%%%%%%%%%%%%%%%%%%%

            if strcmp(app.sobj.Pattern, 'Moving Bar') ||...
                    strcmp(app.sobj.Pattern, 'Shifting Grating')

                if isfield(app.sobj, 'Stim_valiation_type') &&...
                        strcmp(app.sobj.Stim_valiation_type, 'Free')
                    % va: vector averaging
                    app.imgobj = Get_DSI_OSI_va_Free(app.imgobj);

                else
                    app.imgobj = Get_Trial_Averages_for_orientation(app.imgobj);
                    % va: vector averaging
                    app.imgobj = Get_DSI_va(app.imgobj);
                    app.imgobj = Get_OSI_va(app.imgobj);
                end

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                         %
            % Find tuning/selectivity %
            %                         %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isfield(app.sobj, 'Stim_valiation_type') &&...
                    strcmp(app.sobj.Stim_valiation_type, 'Free')
                app.imgobj = Get_Tuned_ROI_forFreeStim(app.imgobj, app.sobj);
            else
                % Selective ROIs
                app.imgobj = Get_Tuned_ROI_byThreshold(app.imgobj, app.sobj);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                            %
            % Transform the shape of MAT %
            %                            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.imgobj.mat2D = Transform_dFF_stim_average(app.imgobj);
            if strcmp(app.sobj.Pattern, 'Moving Bar')
                %if isfield(app.imgobj, 'stim_average_orientation')
                if isfield(app.imgobj, 'stim_orientations')
                    app.imgobj.mat2Dori = Transform_dFF_stim_average(app.imgobj, 1);
                end
            end

        end

        % Value changed function: SelectROIs
        function SelectROIsValueChanged(app, event)
            Select_ROI_for_plot(app)
            Plot_dFF_selected(app)
        end

        % Value changed function: DeselectROIs
        function DeselectROIsValueChanged(app, event)
            Select_ROI_for_plot(app)
            Plot_dFF_selected(app)
        end

        % Button pushed function: ShowSelected
        function ShowSelectedButtonPushed(app, event)
            Show_selected_response(app)
        end

        % Button pushed function: ShowColormap
        function ShowColormapButtonPushed(app, event)
            Show_Colormap_allROIs(app.imgobj)
            if strcmp(app.sobj.Pattern, 'Moving Bar') &&...
                ~strcmp(app.sobj.Stim_valiation_type, 'Free')
                Show_Colormap_allROIs_OS(app.imgobj)
            end
        end

        % Button pushed function: ShowTuningSelected
        function ShowTuningSelectedButtonPushed(app, event)
            Plot_Stim_Tuning_selected(app);
        end

        % Button pushed function: ShowROIMap
        function ShowROIMapButtonPushed(app, event)
            Plot_Stim_Tuning_ROImap(app)
            Show_ROImap(app);
        end

        % Button pushed function: Bootstrap
        function BootstrapButtonPushed(app, event)
            if ~app.imgobj.bstrpDone || app.newbootstrapCheckBox.Value
                Run_Bootstrap(app)
            end
            app.newbootstrapCheckBox.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_2PUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {800, 800};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {397, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_2PUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_2PUIFigure.Name = 'BitPlayer_2P';
            app.BitPlayer_2PUIFigure.CloseRequestFcn = createCallbackFcn(app, @BitPlayer_2PUIFigureCloseRequest, true);
            app.BitPlayer_2PUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_2PUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Menu';
            app.Load2P = uibutton(app.LeftPanel, 'push');
            app.Load2P.ButtonPushedFcn = createCallbackFcn(app, @Load2PPushed, true);
            app.Load2P.Text = 'Load 2P';
            app.ROI_Plus = uibutton(app.LeftPanel, 'push');
            app.ROI_Plus.ButtonPushedFcn = createCallbackFcn(app, @ROI_PlusButtonPushed, true);
            app.ROI_Plus.Text = ' ROI +';
            app.ROI_Minus = uibutton(app.LeftPanel, 'push');
            app.ROI_Minus.ButtonPushedFcn = createCallbackFcn(app, @ROI_MinusButtonPushed, true);
            app.ROI_Minus.Text = ' ROI -';
            app.ROIEditFieldLabel = uilabel(app.LeftPanel);
            app.ROIEditFieldLabel.Text = 'ROI #';
            app.n_ROI = uieditfield(app.LeftPanel, 'numeric');
            app.n_ROI.Limits = [1 Inf];
            app.n_ROI.ValueChangedFcn = createCallbackFcn(app, @n_ROIValueChanged, true);
            app.n_ROI.Value = 1;
            app.SelectLabel = uilabel(app.LeftPanel);
            app.SelectLabel.Text = 'Select: ';
            app.SelectROIs = uieditfield(app.LeftPanel, 'text');
            app.SelectROIs.ValueChangedFcn = createCallbackFcn(app, @SelectROIsValueChanged, true);
            app.SelectROIs.Value = '1';
            app.DeselectEditFieldLabel = uilabel(app.LeftPanel);
            app.DeselectEditFieldLabel.Text = 'Deselect:';
            app.DeselectROIs = uieditfield(app.LeftPanel, 'text');
            app.DeselectROIs.ValueChangedFcn = createCallbackFcn(app, @DeselectROIsValueChanged, true);
            app.HzEditFieldLabel = uilabel(app.LeftPanel);
            app.HzEditFieldLabel.Enable = 'off';
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.Enable = 'off';
            app.HzEditField.Value = 0.005;
            app.F0framesEditFieldLabel = uilabel(app.LeftPanel);
            app.F0framesEditFieldLabel.Text = 'F0 frames: ';
            app.F0frames = uieditfield(app.LeftPanel, 'text');
            app.F0frames.ValueChangedFcn = createCallbackFcn(app, @F0framesValueChanged, true);
            app.F0frames.Value = '1:100';
            app.ApplyChanges = uibutton(app.LeftPanel, 'state');
            app.ApplyChanges.ValueChangedFcn = createCallbackFcn(app, @ApplyChangesValueChanged, true);
            app.ApplyChanges.Text = 'Apply Changes to dF/F';
            app.Detrend = uibutton(app.LeftPanel, 'state');
            app.Detrend.ValueChangedFcn = createCallbackFcn(app, @DetrendValueChanged, true);
            app.Detrend.Text = 'Detrend';
            app.Lowcutfilter = uibutton(app.LeftPanel, 'state');
            app.Lowcutfilter.ValueChangedFcn = createCallbackFcn(app, @LowcutfilterValueChanged, true);
            app.Lowcutfilter.Enable = 'off';
            app.Lowcutfilter.Text = 'Low cut fileter';
            app.Offset = uibutton(app.LeftPanel, 'state');
            app.Offset.ValueChangedFcn = createCallbackFcn(app, @OffsetValueChanged, true);
            app.Offset.Text = 'Offset';
            app.Zscore = uibutton(app.LeftPanel, 'state');
            app.Zscore.ValueChangedFcn = createCallbackFcn(app, @ZscoreValueChanged, true);
            app.Zscore.Text = 'Z-scored';
            app.AveragedbyStim = uibutton(app.LeftPanel, 'push');
            app.AveragedbyStim.ButtonPushedFcn = createCallbackFcn(app, @AveragedbyStimButtonPushed, true);
            app.AveragedbyStim.Enable = 'off';
            app.AveragedbyStim.Text = 'Averaged by Stim';
            app.ShowSelected = uibutton(app.LeftPanel, 'push');
            app.ShowSelected.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedButtonPushed, true);
            app.ShowSelected.Enable = 'off';
            app.ShowSelected.Text = 'Show response (selected)';
            app.ShowColormap = uibutton(app.LeftPanel, 'push');
            app.ShowColormap.ButtonPushedFcn = createCallbackFcn(app, @ShowColormapButtonPushed, true);
            app.ShowColormap.Enable = 'off';
            app.ShowColormap.Text = 'Color map (All)';
            app.ShowTuningSelected = uibutton(app.LeftPanel, 'push');
            app.ShowTuningSelected.ButtonPushedFcn = createCallbackFcn(app, @ShowTuningSelectedButtonPushed, true);
            app.ShowTuningSelected.Enable = 'off';
            app.ShowTuningSelected.Text = 'Tuning (selected)';
            app.ShowROIMap = uibutton(app.LeftPanel, 'push');
            app.ShowROIMap.ButtonPushedFcn = createCallbackFcn(app, @ShowROIMapButtonPushed, true);
            app.ShowROIMap.Enable = 'off';
            app.ShowROIMap.Text = 'ROI map';
            app.Bootstrap = uibutton(app.LeftPanel, 'push');
            app.Bootstrap.ButtonPushedFcn = createCallbackFcn(app, @BootstrapButtonPushed, true);
            app.Bootstrap.Enable = 'off';
            app.Bootstrap.Text = 'Bootstrap & Fit';
            app.FileName = uilabel(app.LeftPanel);
            app.FileName.Text = 'File:';
            app.DebugButton = uibutton(app.LeftPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.Saveas = uibutton(app.LeftPanel, 'push');
            app.Saveas.ButtonPushedFcn = createCallbackFcn(app, @SaveasPushed, true);
            app.Saveas.Text = 'Save as...';
            app.newbootstrapCheckBox = uicheckbox(app.LeftPanel);
            app.newbootstrapCheckBox.Enable = 'off';
            app.newbootstrapCheckBox.Text = 'new bootstrap';
            app.ImagesizeLabel = uilabel(app.LeftPanel);
            app.ImagesizeLabel.Text = 'Image size:';
            app.ImageSize = uieditfield(app.LeftPanel, 'numeric');
            app.ImageSize.Limits = [1 Inf];
            app.ImageSize.ValueChangedFcn = createCallbackFcn(app, @ImageSizeValueChanged, true);
            app.ImageSize.Value = 320;
            app.SaveFileName = uieditfield(app.LeftPanel, 'text');
            app.SaveFileName.ValueChangedFcn = createCallbackFcn(app, @SaveFileNameValueChanged, true);
            app.SaveFileName.Value = '*****.mat';
            app.FVsamptLabel = uilabel(app.LeftPanel);
            app.FVsamptLabel.Text = 'FV sampt:';
            app.FVsampt = uieditfield(app.LeftPanel, 'numeric');
            app.FVsampt.Limits = [0 1];
            app.FVsampt.ValueDisplayFormat = '%11.6g';
            app.FVsampt.ValueChangedFcn = createCallbackFcn(app, @FVsamptValueChanged, true);
            app.FVsampt.Value = 0.574616;
            app.Get_F0Button = uibutton(app.LeftPanel, 'state');
            app.Get_F0Button.ValueChangedFcn = createCallbackFcn(app, @Get_F0ButtonValueChanged, true);
            app.Get_F0Button.Text = 'Get F0';
            app.Get_FVsamptButton = uibutton(app.LeftPanel, 'state');
            app.Get_FVsamptButton.ValueChangedFcn = createCallbackFcn(app, @Get_FVsamptButtonValueChanged, true);
            app.Get_FVsamptButton.Text = 'Get FVsampt';
            app.NumbootstrpEditFieldLabel = uilabel(app.LeftPanel);
            app.NumbootstrpEditFieldLabel.Text = 'Num bootstrp';
            app.n_bootstrp = uieditfield(app.LeftPanel, 'numeric');
            app.n_bootstrp.ValueDisplayFormat = '%11.6g';
            app.n_bootstrp.Value = 5000;
            app.RemoveSacTrials = uibutton(app.LeftPanel, 'state');
            app.RemoveSacTrials.Text = 'Remove saccade/blink trials';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'View 2P Data';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'ROI#  ')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'dF/F0')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Selected')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'dF/F0')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.BitPlayer_2PUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_2P(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BitPlayer_2PUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BitPlayer_2PUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_2PUIFigure)
        end
    end
end