classdef BitPlayer_Analysys_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        OpenmatButton                matlab.ui.control.Button
        FileName                     matlab.ui.control.Label
        FileName_2                   matlab.ui.control.Label
        FileName_3                   matlab.ui.control.Label
        FileName_4                   matlab.ui.control.Label
        PlusButton                   matlab.ui.control.Button
        MinusButton                  matlab.ui.control.Button
        TrialEditFieldLabel          matlab.ui.control.Label
        Trial_n                      matlab.ui.control.NumericEditField
        SavetoFileButton             matlab.ui.control.Button
        SaveFileNameEditFieldLabel   matlab.ui.control.Label
        SaveFileNameEditField        matlab.ui.control.EditField
        PlusButton_2                 matlab.ui.control.Button
        FVsamptLabel                 matlab.ui.control.Label
        Trial_n_2                    matlab.ui.control.NumericEditField
        PhotosensorthresholdmVLabel  matlab.ui.control.Label
        Trial_n_3                    matlab.ui.control.NumericEditField
        PlusButton_3                 matlab.ui.control.Button
        PlusButton_4                 matlab.ui.control.Button
        PlusButton_5                 matlab.ui.control.Button
        PlusButton_6                 matlab.ui.control.Button
        PlusButton_7                 matlab.ui.control.Button
        PlusButton_8                 matlab.ui.control.Button
        ThresholdmVSlider            matlab.ui.control.Slider
        Open2PWindowButton           matlab.ui.control.StateButton
        SavetoWSButton               matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        EditField                    matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %File path
            mypath = '~/Research/BitPlayer/Analysis/';
            cd(mypath);
            
            %% for python
            my_pypath = './Extra/Python_Mymodules';
            !export PYTHONPATH="$PYTHONPATH:/home/lattice/Research/BitPlayer/Analysys/Python_Mymodules/"
            pypath = py.sys.path;
            if count(pypath, my_pypath) == 0
                insert(pypath, int32(0),my_pypath);
                disp('Add PYTHONPATH')
            end
            
            py.bfloadpy.ck_load();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {800, 800};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Menu';
            app.OpenmatButton = uibutton(app.LeftPanel, 'push');
            app.OpenmatButton.Text = 'Open .mat';
            app.FileName = uilabel(app.LeftPanel);
            app.FileName.Text = 'File Name:';
            app.FileName_2 = uilabel(app.LeftPanel);
            app.FileName_2.Text = 'Stim:';
            app.FileName_3 = uilabel(app.LeftPanel);
            app.FileName_3.Text = 'Params1:';
            app.FileName_4 = uilabel(app.LeftPanel);
            app.FileName_4.Text = 'Params2:';
            app.PlusButton = uibutton(app.LeftPanel, 'push');
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.LeftPanel, 'push');
            app.MinusButton.Text = '-';
            app.TrialEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialEditFieldLabel.Text = 'Trial  #:';
            app.Trial_n = uieditfield(app.LeftPanel, 'numeric');
            app.SavetoFileButton = uibutton(app.LeftPanel, 'push');
            app.SavetoFileButton.Text = 'Save to File';
            app.SaveFileNameEditFieldLabel = uilabel(app.LeftPanel);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name:';
            app.SaveFileNameEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveFileNameEditField.Value = 'aaaaa.mat';
            app.PlusButton_2 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_2.Text = 'Get F0';
            app.FVsamptLabel = uilabel(app.LeftPanel);
            app.FVsamptLabel.Text = 'FV sampt:';
            app.Trial_n_2 = uieditfield(app.LeftPanel, 'numeric');
            app.Trial_n_2.ValueDisplayFormat = '%11.6g';
            app.Trial_n_2.Value = 0.574616;
            app.PhotosensorthresholdmVLabel = uilabel(app.LeftPanel);
            app.PhotosensorthresholdmVLabel.Text = 'Photo sensor threshold:                   mV';
            app.Trial_n_3 = uieditfield(app.LeftPanel, 'numeric');
            app.Trial_n_3.ValueDisplayFormat = '%11.3g';
            app.Trial_n_3.Value = 80;
            app.PlusButton_3 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_3.Text = 'Get saccade';
            app.PlusButton_4 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_4.Text = 'Update all event';
            app.PlusButton_5 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_5.Text = 'Clear all event';
            app.PlusButton_6 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_6.Text = 'Clear this event';
            app.PlusButton_7 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_7.Text = 'Plot saccade';
            app.PlusButton_8 = uibutton(app.LeftPanel, 'push');
            app.PlusButton_8.Text = 'Plot all saccades';
            app.ThresholdmVSlider = uislider(app.LeftPanel);
            app.ThresholdmVSlider.Limits = [0 200];
            app.ThresholdmVSlider.MajorTicks = [0 50 100 150 200];
            app.ThresholdmVSlider.Value = 80;
            app.Open2PWindowButton = uibutton(app.LeftPanel, 'state');
            app.Open2PWindowButton.Text = 'Open 2P Window';
            app.SavetoWSButton = uibutton(app.LeftPanel, 'push');
            app.SavetoWSButton.Text = 'Save to WS';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'View Data';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Eye Center -- Horizontal')
            ylabel(app.UIAxes, 'Temp <---> Nasal')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Eye Center -- Vertical')
            ylabel(app.UIAxes_2, 'Up <---> Down')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Eye -- Velocity')
            ylabel(app.UIAxes_3, 'Pixel / sec')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Locomotion / Eye Open Ratio')
            ylabel(app.UIAxes_4, 'Angle')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Photo sensor -- Visual Stim Timing')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'mV')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_Analysys_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end