classdef BitPlayer_daq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_DAQAppUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        DAQOFFButton              matlab.ui.control.StateButton
        SAVEOFFButton             matlab.ui.control.StateButton
        StartRECButton            matlab.ui.control.StateButton
        EXITButton                matlab.ui.control.Button
        SetFileNameButton         matlab.ui.control.Button
        FileName                  matlab.ui.control.Label
        RecTimesEditFieldLabel    matlab.ui.control.Label
        RecTimesEditField         matlab.ui.control.NumericEditField
        ITIsEditFieldLabel        matlab.ui.control.Label
        ITIsEditField             matlab.ui.control.NumericEditField
        SamplingHzEditFieldLabel  matlab.ui.control.Label
        SamplingHzEditField       matlab.ui.control.NumericEditField
        EditField_4Label          matlab.ui.control.Label
        EditField_4               matlab.ui.control.NumericEditField
        EditField_5Label          matlab.ui.control.Label
        EditField_5               matlab.ui.control.NumericEditField
        CenterPanel               matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        UseCameraButton           matlab.ui.control.StateButton
        Image                     matlab.ui.control.Image
        SetROIButton              matlab.ui.control.StateButton
        SaveCameraButton          matlab.ui.control.StateButton
        LiveViewButton            matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        recobj % struct for recording parameters
        s % DAQ AI session
        sOut %DAQ AO
        dio %DAQ DIO
        capture %data read from DAQ
        dev %DAQ device info
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
            %Project path setting
            mypath = '~/Documents/MATLAB/BitPlayer/';
            cd(mypath)
            
            %subfolders
            ds = {'CheckParams','CommonFcns', 'PyFcns'};
            for i = 1:length(ds)
                p = genpath([mypath, ds{i}]);
                addpath(p);
            end
            %}
            
        end

        % Value changed function: DAQOFFButton
        function DAQOFFButtonValueChanged(app, event)
            value = app.DAQOFFButton.Value;
            if value
                app.DAQOFFButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.DAQOFFButton.Text = "DAQ-ON";
                [app.s, app.sOut, app.dio, app.capture, app.dev] = daq_ini(1); %DAQ for iRecHS2
                
                %trigger state off(off:1, on:0)
                outputSingleScan(app.dio.VSon,1)
            else
                app.DAQOFFButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.DAQOFFButton.Text = "DAQ-OFF";
            end
        end

        % Value changed function: SAVEOFFButton
        function SAVEOFFButtonValueChanged(app, event)
            value = app.SAVEOFFButton.Value;
            if value
                app.SAVEOFFButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.SAVEOFFButton.Text = "SAVE-ON";
            else
                app.SAVEOFFButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.SAVEOFFButton.Text = "SAVE-OFF";
            end
            
        end

        % Value changed function: StartRECButton
        function StartRECButtonValueChanged(app, event)
            value = app.StartRECButton.Value;
            if value
                app.StartRECButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.StartRECButton.Text = "Recording";
                loop_daq_test(app);
            else
                app.StartRECButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.StartRECButton.Text = "Start REC";
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_DAQAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {702, 702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: SetFileNameButton
        function SetFileNameButtonPushed(app, event)
            %{
            if isfield(app.recobj, 'dirname') == 0 % When choosing a file name for the first time
                dirname1 = app.recobj.save_dirname;
                [app.recobj.fname, app.recobj.dirname] = uiputfile([dirname1,'/*.mat']);
            else %open the same folder when any foder was selected previously.
                if app.recobj.dirname == 0
                    app.recobj.dirname = app.recobj.save_dirname;
                else
                    [app.recobj.fname, app.recobj.dirname] = uiputfile('*.mat', 'Select File to Write', app.recobj.dirname);
                end
            end
            app.FileName.Text = app.recobj.fname;
            %}
            
            %GUI test
            text = "SC.mat";
            app.FileName.Text = text;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            %{
            global sobj
            global dev
            global plotUIobj
            global getfineUIobj
            global imaq
            
            delete(s)
            
            if UseCam==1
                if exist('imaq' ,'var')
                    delete(imaq.vid)
                    clear imaq
                end
            end
            
            if isempty(dev)
            else
                daq.reset;
            end
            
            if sobj.ScrNum ~= 0
                sca;
            end
            
            %clear windows, variables
            if isstruct(plotUIobj)
                if isfield(plotUIobj, 'fig')
                    close(plotUIobj.fig)
                end
            end
            
            if isstruct(getfineUIobj)
                if isfield(getfineUIobj, 'fig')
                    close(getfineUIobj.fig)
                end
            end
            sca; %Execute Screen('CloseAll');
            %}
            close all hidden;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_DAQAppUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_DAQAppUIFigure.Name = 'Bit Player_DAQ.App';
            app.BitPlayer_DAQAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_DAQAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'DAQ';
            app.DAQOFFButton = uibutton(app.LeftPanel, 'state');
            app.DAQOFFButton.ValueChangedFcn = createCallbackFcn(app, @DAQOFFButtonValueChanged, true);
            app.DAQOFFButton.Text = 'DAQ-OFF';
            app.SAVEOFFButton = uibutton(app.LeftPanel, 'state');
            app.SAVEOFFButton.ValueChangedFcn = createCallbackFcn(app, @SAVEOFFButtonValueChanged, true);
            app.SAVEOFFButton.Text = 'SAVE-OFF';
            app.StartRECButton = uibutton(app.LeftPanel, 'state');
            app.StartRECButton.ValueChangedFcn = createCallbackFcn(app, @StartRECButtonValueChanged, true);
            app.StartRECButton.Text = 'Start REC';
            app.EXITButton = uibutton(app.LeftPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.SetFileNameButton = uibutton(app.LeftPanel, 'push');
            app.SetFileNameButton.ButtonPushedFcn = createCallbackFcn(app, @SetFileNameButtonPushed, true);
            app.SetFileNameButton.Text = 'Set File Name';
            app.FileName = uilabel(app.LeftPanel);
            app.FileName.Text = 'Unset file name';
            app.RecTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.RecTimesEditFieldLabel.Text = 'Rec Time (s)';
            app.RecTimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ITIsEditFieldLabel = uilabel(app.LeftPanel);
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingHzEditFieldLabel.Text = 'Sampling (Hz)';
            app.SamplingHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_4Label = uilabel(app.LeftPanel);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_5Label = uilabel(app.LeftPanel);
            app.EditField_5Label.Text = 'Edit Field';
            app.EditField_5 = uieditfield(app.LeftPanel, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Live Plots';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Eye Horizontal (V)')
            app.UIAxes.XTick = [];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Eye Vertical (V)')
            app.UIAxes_2.XTick = [];
            app.UIAxes_3 = uiaxes(app.CenterPanel);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Photo Sensor (V)')
            app.UIAxes_3.XTick = [];
            app.UIAxes_4 = uiaxes(app.CenterPanel);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, 'Rotally Angle (deg)')
            app.UIAxes_4.XTick = [];
            app.UIAxes_5 = uiaxes(app.CenterPanel);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, 'Live Trigger')
            app.UIAxes_5.XTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'IMAQ';
            app.UseCameraButton = uibutton(app.RightPanel, 'state');
            app.UseCameraButton.Text = 'Use Camera';
            app.Image = uiimage(app.RightPanel);
            app.SetROIButton = uibutton(app.RightPanel, 'state');
            app.SetROIButton.Text = 'Set ROI';
            app.SaveCameraButton = uibutton(app.RightPanel, 'state');
            app.SaveCameraButton.Text = 'Save Camera';
            app.LiveViewButton = uibutton(app.RightPanel, 'state');
            app.LiveViewButton.Text = 'Live View';
            % Show the figure after all components are created
            app.BitPlayer_DAQAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_daq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BitPlayer_DAQAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_DAQAppUIFigure)
        end
    end
end