classdef BitPlayer_ptb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_PTBUIFigure      matlab.ui.Figure
        OpenPTBButton              matlab.ui.control.StateButton
        ClosePTBButton             matlab.ui.control.StateButton
        FileNameLabel              matlab.ui.control.Label
        VisualStim1Panel           matlab.ui.container.Panel
        StimPositionDropDownLabel  matlab.ui.control.Label
        StimPositionDropDown       matlab.ui.control.DropDown
        StimPatternsDropDownLabel  matlab.ui.control.Label
        StimPatternsDropDown       matlab.ui.control.DropDown
        ShapeDropDownLabel         matlab.ui.control.Label
        ShapeDropDown              matlab.ui.control.DropDown
        SignalfromBP1LampLabel     matlab.ui.control.Label
        SignalfromBP1Lamp          matlab.ui.control.Lamp
        StartPTBButton             matlab.ui.control.StateButton
        FileNameButton             matlab.ui.control.Button
    end

    % Properties
    properties (Access = public)
        n = 1 % Trial number
        
        imgobj = [] % imaging parameters 
        mainvar = [] % data paths
        ParamsSave = [] % Params in each trial
        DataSave =[]  % loaded Data1
        recobj =[] % Recording parameters
        sobj = [] % Stimlus Parameters
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %add subfolders
            
        end

        % Value changed function: StartPTBButton
        function StartPTBButtonValueChanged(app, event)
            value = app.StartPTBButton.Value;
            if value
                app.StartPTBButton.Text = 'Wait Trigger';
                app.StartPTBButton.BackgroundColor = [1,1,0];
                disp('start');
                loop_ptb_test(app);
                
            else
                app.StartPTBButton.Text = 'Start PTB';
                app.StartPTBButton.BackgroundColor = [0.96, 0.96, 0.96];
            end
            
            %Start PTB loop
        end

        % Button pushed function: FileNameButton
        function FileNameButtonPushed(app, event)
            app.FileNameLabel.Text = 'SC.mat';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_PTBUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_PTBUIFigure.Name = 'BitPlayer_PTB';
            app.OpenPTBButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.OpenPTBButton.Text = 'Open PTB';
            app.ClosePTBButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.ClosePTBButton.Text = 'Close PTB';
            app.FileNameLabel = uilabel(app.BitPlayer_PTBUIFigure);
            app.FileNameLabel.Text = 'Unset';
            app.VisualStim1Panel = uipanel(app.BitPlayer_PTBUIFigure);
            app.VisualStim1Panel.Title = 'Visual Stim 1';
            app.StimPositionDropDownLabel = uilabel(app.VisualStim1Panel);
            app.StimPositionDropDownLabel.Text = 'Stim Position';
            app.StimPositionDropDown = uidropdown(app.VisualStim1Panel);
            app.StimPositionDropDown.Items = {'Rand', 'Order', 'Fix'};
            app.StimPositionDropDown.Value = 'Rand';
            app.StimPatternsDropDownLabel = uilabel(app.VisualStim1Panel);
            app.StimPatternsDropDownLabel.Text = 'Stim Patterns';
            app.StimPatternsDropDown = uidropdown(app.VisualStim1Panel);
            app.StimPatternsDropDown.Items = {'Uni', 'stim2', 'stim3'};
            app.StimPatternsDropDown.Value = 'Uni';
            app.ShapeDropDownLabel = uilabel(app.VisualStim1Panel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.VisualStim1Panel);
            app.ShapeDropDown.Items = {'Circle', 'Square'};
            app.ShapeDropDown.Value = 'Circle';
            app.SignalfromBP1LampLabel = uilabel(app.BitPlayer_PTBUIFigure);
            app.SignalfromBP1LampLabel.Text = 'Signal from BP1';
            app.SignalfromBP1Lamp = uilamp(app.BitPlayer_PTBUIFigure);
            app.StartPTBButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.StartPTBButton.ValueChangedFcn = createCallbackFcn(app, @StartPTBButtonValueChanged, true);
            app.StartPTBButton.Text = 'Start PTB';
            app.FileNameButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.FileNameButton.ButtonPushedFcn = createCallbackFcn(app, @FileNameButtonPushed, true);
            app.FileNameButton.Text = {'File Name'; ''};
            % Show the figure after all components are created
            app.BitPlayer_PTBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_ptb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BitPlayer_PTBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_PTBUIFigure)
        end
    end
end