classdef BitPlayer_daq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TTLAnalogOutputPanel      matlab.ui.container.Panel
        SinglePulseWidthLabel     matlab.ui.control.Label
        TTLPulseNum               matlab.ui.control.NumericEditField
        DurationmsLabel_2         matlab.ui.control.Label
        DutyCycleLabel            matlab.ui.control.Label
        TTLDuration               matlab.ui.control.NumericEditField
        DurationmsLabel           matlab.ui.control.Label
        TTLDutyCycle              matlab.ui.control.NumericEditField
        TTLFrequency              matlab.ui.control.NumericEditField
        FreqencyHzLabel           matlab.ui.control.Label
        TTLDelay                  matlab.ui.control.NumericEditField
        SetDurationDropDown       matlab.ui.control.DropDown
        SetDurationDropDownLabel  matlab.ui.control.Label
        TTLSwitch                 matlab.ui.control.StateButton
        DelaymsEditFieldLabel     matlab.ui.control.Label
        IMAQPanel                 matlab.ui.container.Panel
        VideoFrameRate            matlab.ui.control.NumericEditField
        CapturedurationmsLabel_2  matlab.ui.control.Label
        DelaymsEditFieldLabel_2   matlab.ui.control.Label
        VideoCaptureTime          matlab.ui.control.NumericEditField
        CapturedurationmsLabel    matlab.ui.control.Label
        VideoCaptureDelay         matlab.ui.control.NumericEditField
        CameraSave                matlab.ui.control.StateButton
        AdujstROIButton           matlab.ui.control.StateButton
        CameraPreviewButton       matlab.ui.control.StateButton
        CameraButton              matlab.ui.control.StateButton
        CameraUIAxes              matlab.ui.control.UIAxes
        LIVEPlotPanel             matlab.ui.container.Panel
        PlotRecorded              matlab.ui.control.UIAxes
        TriggerMonitor            matlab.ui.control.UIAxes
        RotaryEncoder             matlab.ui.control.UIAxes
        PhotoSensor               matlab.ui.control.UIAxes
        PupilCenterXY             matlab.ui.control.UIAxes
        DAQPanel                  matlab.ui.container.Panel
        DebugButton               matlab.ui.control.Button
        EXITButton                matlab.ui.control.Button
        StandAloneModeButton      matlab.ui.control.StateButton
        DAQSTOPButton             matlab.ui.control.Button
        DAQSTARTButton            matlab.ui.control.Button
        AutoScaleYSwitchLabel     matlab.ui.control.Label
        SamplingkHzLabel          matlab.ui.control.Label
        SAVEOFFButton             matlab.ui.control.Button
        SAVEONButton              matlab.ui.control.Button
        StateText                 matlab.ui.control.Label
        AutoScaleYSwitch          matlab.ui.control.Switch
        LiveTime                  matlab.ui.control.NumericEditField
        LiveTimemsEditFieldLabel  matlab.ui.control.Label
        SamplingRate              matlab.ui.control.NumericEditField
        ITI                       matlab.ui.control.NumericEditField
        ITIsecEditFieldLabel      matlab.ui.control.Label
        RecTimemsEditFieldLabel   matlab.ui.control.Label
        RecTime                   matlab.ui.control.NumericEditField
        FileNameTxt               matlab.ui.control.Label
        SaveasLabel               matlab.ui.control.Label
        EditFieldFileName         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        EditFieldMouseID          matlab.ui.control.EditField
        MouseIDEditFieldLabel     matlab.ui.control.Label
    end

    % BitPlayer for daq controle.

    % 2023/03/22 version 0.4.1: Update structure of loop
    % 2023/03/20 version 0.3.x: Loop is not stable???
    % 2023/02/08 version 0.3.3: Increase buffer size to prevent failure of
    % saving data.
    % 2023/02/02 version 0.3.2: Correct store save data
    % 2023/01/30 version 0.3.1: Connectino b/w PTB
    % 2023/01/10 version 0.3.0: AnalogOutput function, Camera GUI
    % 2023/01/04 version 0.2.0: New mlapp file, add TTL
    % 2022/08/31 version 0.1, lattice
    % Refrence the Code for analog trigger and live plotting function
    %%% Analog Trigger App example
    %%% 2019/01/11 version 1.0 Andrei Ursache
    %%% 2019/12/21 version 1.1, AU, updated DAQ code from Session to DataAcquisition interface

    properties (Access = public)
        %Parameters
        recobj;                     % Struct for recording parameters
        c;                          % Struct for capture configuration
        imaq;                       % Struct for camera configuration

        %DAQ
        d_in;                       % daq interface for analog and counter inputs
        d_out;                      % daq interface for digital output (use for TTL trigger)
        d_out_ao;                   % daq interface for TTL putput

        %State
        loopON = false;             % Loop flag
        saveON = false;             % Save flag
        CurrentState = 'DAQstop';   %Current state

        %Plot 
        plot1;
        plot2;
        plot3;
        plot4;
        plot5;

        %State
        DAQt;                       % Timer from the first loop.
        TrigActive = false;         % Flag for triggered condition
        RTS = false;                % Flag for serial connection RTS from PTB
        TrigMoment =[];             % Timing when trigger signal reached
        CaptureStartMoment;         % Timestamp of trigger moment.
        capturing;

        %DATA
        DataFIFOBuffer;             % Timestamps FIFO buffer for live plot (of latest N sec)
        TimestampsFIFOBuffer;       % Data FIFO buffer for live plot
        CaptureData;                % Captured data
        CaptureTimestamps;          % Captured timestamps
        SaveData;
        SaveTimestamps;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Project path setting
            mypath = 'C:\Users\lattice\Research\BitPlayer\DAQ\';
            cd(mypath)
            addpath('..\Common\');

            subfolders = {'AO', 'Capture','GUI', 'Params', 'Video'};
            for i = 1:length(subfolders)
                p = genpath([mypath, subfolders{i}]);
                addpath(p)
            end

            %GUI button enable off
            setAppViewState(app, 'Setup')

            % Initialization
            recobj_ini(app);
            DAQ_ini(app);
            plot_ini(app);
            capture_ini(app); % DAQ capture settings
            %imaq_ini(app);

            % GUI
            stateMonitor(app);
            setAppViewState(app, 'Ready')
            
            %Generate Data fodler (named as current date)
            app.recobj.SaveDir = ...
                CreateDataFolder('C:\Users\lattice\Research\BitPlayerData\', 'daq');
            app.recobj.SaveMovieDir = ...
                CreateDataFolder('C:\Users\lattice\Research\BitPlayerData\', 'Camera');

        end

        % Value changed function: EditFieldMouseID
        function EditFieldMouseIDValueChanged(app, event)
            app.recobj.SaveDirMouse =...
                CreateMouseIDFolder(app, 'daq');

            app.recobj.SaveMovieDirMouse = ...
                CreateMouseIDFolder(app, 'video');

        end

        % Value changed function: EditFieldFileName
        function EditFieldFileNameValueChanged(app, event)
            value = app.EditFieldFileName.Value;
            UpdateSaveFileName(app, value, 'daq');
            
        end

        % Value changed function: RecTime
        function RecTimeValueChanged(app, event)
            value = app.RecTime.Value;
            app.recobj.rect = value;
            app.recobj.recp = app.recobj.sampf * value/1000;
            capture_ini(app);
            CheckDuration_Camera(app);
            
        end

        % Value changed function: ITI
        function ITIValueChanged(app, event)
            %value = app.ITI.Value;
            app.recobj.interval = app.ITI.Value;
            
        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            %kHz in GUI
            value = app.SamplingRate.Value;

            %kHz -> Hz
            app.recobj.sampf = value * 1000;
            app.d_in.Rate = app.recobj.sampf;
            app.d_out_ao.Rate = app.recobj.sampf;
            capture_ini(app);
            Generate_TTL_AO(app)
        end

        % Button pushed function: SAVEONButton
        function SAVEONButtonPushed(app, event)
            if isempty(app.EditFieldFileName.Value) || isempty(app.EditFieldMouseID.Value)
                errordlg('Input Mouse ID or File Name, first!!!');
            else
                app.SAVEOFFButton.Enable = "on";
                app.saveON = true;
                app.SAVEONButton.Enable = "off";
                UpdateSaveFileName(app, app.EditFieldFileName.Value, 'daq')
                
                if app.CameraButton.Value
                    app.CameraSave.Enable = 'on';
                end
            end
        end

        % Button pushed function: SAVEOFFButton
        function SAVEOFFButtonPushed(app, event)
            app.SAVEOFFButton.Enable = "off";
            app.saveON = false;
            app.SAVEONButton.Enable = "on";
            app.CameraSave.Enable = 'off';
            app.CameraSave.Value = 0;
            app.CameraSave.BackgroundColor = [0.96, 0.96, 0.96];
            app.CameraSave.Text = 'SAVE CAM OFF';
        end

        % Button pushed function: DAQSTARTButton
        function DAQSTARTButtonPushed(app, event)
            % GUI 
            app.DAQSTOPButton.Enable = "on";
            app.loopON = true;
            app.DAQSTARTButton.Enable = "off";
            app.SAVEOFFButton.Enable = "off";

            setAppViewState(app, 'Recording')

            % Reset FIFO buffer
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];

            % Reset Data and Timestamps
            app.SaveTimestamps = [];
            app.SaveData = [];

            %%%%%%%%%%
            main_loop(app);

            %%%%%%%%%%
        end

        % Button pushed function: DAQSTOPButton
        function DAQSTOPButtonPushed(app, event)
            app.loopON = false;
            write(app.d_out, [0, 0, 0, 0]);
            %
            stop(app.d_in);
            flush(app.d_in);

            if isfield(app.imaq, 'vid') && isrunning(app.imaq.vid)
                stop(app.imaq.vid)            
            end

            % GUI

            app.DAQSTOPButton.Enable = "off";
            app.DAQSTARTButton.Enable = "on";

            app.SAVEOFFButton.Enable = "off";
            app.SAVEONButton.Enable = "on";

            setAppViewState(app, 'Ready')

            app.CurrentState = 'DAQstop';
            app.CaptureData = [];
            app.CaptureTimestamps = [];
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];

            %% Video setting off %%
            app.CameraSave.Value = 0;
            app.CameraSave.Enable ='off';
            app.CameraSave.Text = "SAVE CAM OFF";
            app.CameraSave.BackgroundColor = [0.96, 0.96, 0.96];
            app.CameraPreviewButton.Enable = 'on';
            app.AdujstROIButton.Enable = 'on';
            app.VideoFrameRate.Enable = 'on';
            app.VideoCaptureTime.Enable = 'on';
            app.VideoCaptureDelay.Enable = 'on';

            %%
            stateMonitor(app)
        end

        % Value changed function: StandAloneModeButton
        function StandAloneModeButtonValueChanged(app, event)
            obj = app.StandAloneModeButton;
            val = obj.Value;
            if val
                obj.BackgroundColor = [1, 0, 0];
                obj.FontColor = [1, 1, 1];
                app.ITI.Enable = "on";
            else 
                obj.BackgroundColor = [1, 0.82, 0.82];
                obj.FontColor = [0, 0, 0];
                app.ITI.Enable = "off";
            end
            
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close all;
            app.delete;
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            assignin('base', "app", app)
        end

        % Value changed function: SetDurationDropDown
        function SetDurationDropDownValueChanged(app, event)
            value = app.SetDurationDropDown.Value;
            switch value
                case 'Fix Duration'
                    app.TTLPulseNum.Editable = 'off';
                    app.TTLPulseNum.BackgroundColor = [0.96, 0.96, 0.96];
%                     app.TTLPulseNum.Value = app.TTLFrequency.Value *...
%                         app.TTLDuration.Value/1000; 

                    app.TTLDuration.Editable = 'on';
                    app.TTLDuration.BackgroundColor = [1.00, 1.00, 0.73];

                case 'Fix Pulse Number'
                    app.TTLPulseNum.Editable = 'on';
                    app.TTLPulseNum.BackgroundColor = [1.00, 1.00, 0.73];

                    app.TTLDuration.Editable = 'off';
                    app.TTLDuration.BackgroundColor = [0.96, 0.96, 0.96];
%                     app.TTLDuration.Value = ...
%                         app.TTLPulseNum.Value / app.TTLFrequency.Value * 1000; %ms

            end
            CheckDuration(app);
            
        end

        % Value changed function: TTLSwitch
        function TTLSwitchValueChanged(app, event)
            val = app.TTLSwitch.Value;
            if val
                app.TTLSwitch.Text = 'TTL ON';
                app.TTLSwitch.BackgroundColor = [0.00,1.00,0.00];

                app.TTLDelay.Enable = 'on';
                app.TTLFrequency.Enable = 'on';
                app.TTLDuration.Enable = 'on';
                app.TTLDutyCycle.Enable = 'on';
                app.SetDurationDropDown.Enable = 'on';
                app.TTLPulseNum.Enable = 'on';
                app.SinglePulseWidthLabel.Enable = 'on';

                CheckDuration(app);
                Generate_TTL_AO(app);
            else
                app.TTLSwitch.Text = 'TTL OFF';
                app.TTLSwitch.BackgroundColor = [0.96,0.96,0.96];

                app.TTLDelay.Enable = 'off';
                app.TTLFrequency.Enable = 'off';
                app.TTLDuration.Enable = 'off';
                app.TTLDutyCycle.Enable = 'off';
                app.SetDurationDropDown.Enable = 'off';
                app.TTLPulseNum.Enable = 'off';
                app.SinglePulseWidthLabel.Enable = 'off';
                if isfield(app.recobj.TTL, 'outputSignal')
                    app.recobj.TTL = rmfield(app.recobj.TTL, 'outputSignal');
                end
            end
        end

        % Value changed function: TTLDelay
        function TTLDelayValueChanged(app, event)
            CheckDuration(app);
            Generate_TTL_AO(app);
        end

        % Value changed function: TTLDuration
        function TTLDurationValueChanged(app, event)
            CheckDuration(app);
            Generate_TTL_AO(app);
        end

        % Value changed function: TTLFrequency
        function TTLFrequencyValueChanged(app, event)
            CheckDuration(app);
            Generate_TTL_AO(app);
        end

        % Value changed function: TTLPulseNum
        function TTLPulseNumValueChanged(app, event)
            CheckDuration(app);
            Generate_TTL_AO(app);
        end

        % Value changed function: TTLDutyCycle
        function TTLDutyCycleValueChanged(app, event)

            if app.TTLDutyCycle.Value > 1 || app.TTLDutyCycle.Value < 0
                errordlg('Dutcy Cycle shoud be from 0 to 1')
                app.TTLDutyCycle.Value = 0.5;
                app.recobj.TTL.DutyCycle = 0.5;
            else
                app.recobj.TTL.DutyCycle = app.TTLDutyCycle.Value;

                app.recobj.TTL.SinglePulseWidth = 1000 * app.recobj.TTL.DutyCycle / app.recobj.TTL.Freq; %ms
                app.SinglePulseWidthLabel.Text = ['Single Pulse Width (ms): '...
                    num2str(app.recobj.TTL.SinglePulseWidth), ' ms'];
            end
            
            CheckDuration(app);
            Generate_TTL_AO(app);

        end

        % Value changed function: CameraSave
        function CameraSaveValueChanged(app, event)
            val = app.CameraSave.Value;
            imaq_Reset_Video(app)
            if val
                % ON
                app.CameraSave.Text = "SAVE CAM ON";
                app.CameraSave.BackgroundColor = [0, 1, 0];
                app.CameraPreviewButton.Enable = 'off';
                app.AdujstROIButton.Enable = 'off';
                app.VideoFrameRate.Enable = 'off';
                app.VideoCaptureTime.Enable = 'off';
                app.VideoCaptureDelay.Enable = 'off';

                % Generate experiment fodler
                if isfield(app.recobj, 'savecount')
                    movie_trial_dir = ...
                        [app.recobj.SaveMovieDirMouse, '\Movie_', num2str(app.recobj.savecount)];
                    app.imaq.movie_trial_dir = movie_trial_dir;
                    if exist(movie_trial_dir, 'dir')==0
                        mkdir(movie_trial_dir)
                    end
                else
                    errordlg('Input Mouse ID or File Name, first!!!');
                    %OFF
                    app.CameraSave.Value = 0;
                    app.CameraSave.Text = "SAVE CAM OFF";
                    app.CameraSave.BackgroundColor = [0.96, 0.96, 0.96];
                    app.CameraPreviewButton.Enable = 'on';
                    app.AdujstROIButton.Enable = 'on';
                    app.VideoFrameRate.Enable = 'on';
                    app.VideoCaptureTime.Enable = 'on';
                    app.VideoCaptureDelay.Enable = 'on';
                end

            else
                %OFF
                app.CameraSave.Text = "SAVE CAM OFF";
                app.CameraSave.BackgroundColor = [0.96, 0.96, 0.96];
                app.CameraPreviewButton.Enable = 'on';
                app.AdujstROIButton.Enable = 'on';
                app.VideoFrameRate.Enable = 'on';
                app.VideoCaptureTime.Enable = 'on';
                app.VideoCaptureDelay.Enable = 'on';
            end
            
        end

        % Value changed function: CameraPreviewButton
        function CameraPreviewButtonValueChanged(app, event)
            value = app.CameraPreviewButton.Value;

            if value
                app.CameraPreviewButton.BackgroundColor = [0, 1, 0];
                
                if isfield(app.imaq, 'roi_position')
                    app.imaq.vid.ROIPosition = app.imaq.roi_position;
                    %Image hundle
                    hImage = image(app.CameraUIAxes, zeros(app.imaq.roi_position(3), app.imaq.roi_position(4), 1));
                else
                    hImage = image(app.CameraUIAxes, zeros(192, 168, 1));
                end
                %Preview ON
                preview(app.imaq.vid, hImage)

            else
                app.CameraPreviewButton.BackgroundColor = [0.96,0.96,0.96];
                %Preview OFF
                stoppreview(app.imaq.vid);
            end

        end

        % Value changed function: AdujstROIButton
        function AdujstROIButtonValueChanged(app, event)
            val = app.AdujstROIButton.Value;
            if val
                %Button ON
                imaq_Reset_ROI(app);

            else
                %Button OFF
                if isfield(app.imaq, 'fig_ROI')
                    delete(app.imaq.fig_ROI)
                else
%                    imaq_Reset_Saccade(app)
                end
            end
            
        end

        % Value changed function: CameraButton
        function CameraButtonValueChanged(app, event)
            value = app.CameraButton.Value;
            if value
                if ~isfield(app, 'imaq')
                    imaq_ini(app);
                end
                setAppViewState(app, 'CameraON')
                
            else
                imaqreset;
                app.imaq = rmfield(app.imaq, 'vid');
                setAppViewState(app, 'CameraOFF')
                
            end
        end

        % Value changed function: VideoCaptureTime
        function VideoCaptureTimeValueChanged(app, event)
            %app.imaq.duration_ms = app.VideoCaptureTime.Value;
            CheckDuration_Camera(app);
            %imaq_Reset_Video(app)
            
        end

        % Value changed function: VideoCaptureDelay
        function VideoCaptureDelayValueChanged(app, event)
            CheckDuration_Camera(app);
            %imaq_Reset_Video(app)
            
            
        end

        % Value changed function: VideoFrameRate
        function VideoFrameRateValueChanged(app, event)
            %imaq_Reset_Video(app)

        end

        % Value changed function: LiveTime
        function LiveTimeValueChanged(app, event)
            value = app.LiveTime.Value;
            app.c.livePlotTimeSpan = value/1000; %ms -> sec
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DAQPanel = uipanel(app.UIFigure);
            app.DAQPanel.Title = 'DAQ';
            app.MouseIDEditFieldLabel = uilabel(app.DAQPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID:';
            app.EditFieldMouseID = uieditfield(app.DAQPanel, 'text');
            app.EditFieldMouseID.ValueChangedFcn = createCallbackFcn(app, @EditFieldMouseIDValueChanged, true);
            app.EditFieldMouseID.Placeholder = 'Mouse ID';
            app.FileNameEditFieldLabel = uilabel(app.DAQPanel);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.EditFieldFileName = uieditfield(app.DAQPanel, 'text');
            app.EditFieldFileName.ValueChangedFcn = createCallbackFcn(app, @EditFieldFileNameValueChanged, true);
            app.EditFieldFileName.Placeholder = 'File Name';
            app.EditFieldFileName.Value = 'daq';
            app.SaveasLabel = uilabel(app.DAQPanel);
            app.SaveasLabel.Text = 'Save as:';
            app.FileNameTxt = uilabel(app.DAQPanel);
            app.FileNameTxt.Text = '_*.mat';
            app.RecTime = uieditfield(app.DAQPanel, 'numeric');
            app.RecTime.ValueDisplayFormat = '%.0f';
            app.RecTime.ValueChangedFcn = createCallbackFcn(app, @RecTimeValueChanged, true);
            app.RecTime.Value = 1000;
            app.RecTimemsEditFieldLabel = uilabel(app.DAQPanel);
            app.RecTimemsEditFieldLabel.Text = 'Rec Time (ms)';
            app.ITIsecEditFieldLabel = uilabel(app.DAQPanel);
            app.ITIsecEditFieldLabel.Text = 'ITI (sec)';
            app.ITI = uieditfield(app.DAQPanel, 'numeric');
            app.ITI.ValueChangedFcn = createCallbackFcn(app, @ITIValueChanged, true);
            app.ITI.Enable = 'off';
            app.ITI.Value = 1;
            app.SamplingRate = uieditfield(app.DAQPanel, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.SamplingRate.Value = 1;
            app.LiveTimemsEditFieldLabel = uilabel(app.DAQPanel);
            app.LiveTimemsEditFieldLabel.Text = 'Live Time (ms)';
            app.LiveTime = uieditfield(app.DAQPanel, 'numeric');
            app.LiveTime.ValueDisplayFormat = '%.0f';
            app.LiveTime.ValueChangedFcn = createCallbackFcn(app, @LiveTimeValueChanged, true);
            app.LiveTime.Value = 500;
            app.AutoScaleYSwitch = uiswitch(app.DAQPanel, 'slider');
            app.AutoScaleYSwitch.Enable = 'off';
            app.AutoScaleYSwitch.Value = 'On';
            app.StateText = uilabel(app.DAQPanel);
            app.StateText.Text = 'Starting up...';
            app.SAVEONButton = uibutton(app.DAQPanel, 'push');
            app.SAVEONButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEONButtonPushed, true);
            app.SAVEONButton.Enable = 'off';
            app.SAVEONButton.Text = 'SAVE-ON';
            app.SAVEOFFButton = uibutton(app.DAQPanel, 'push');
            app.SAVEOFFButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEOFFButtonPushed, true);
            app.SAVEOFFButton.Enable = 'off';
            app.SAVEOFFButton.Text = 'SAVE-OFF';
            app.SamplingkHzLabel = uilabel(app.DAQPanel);
            app.SamplingkHzLabel.Text = 'Sampling (kHz)';
            app.AutoScaleYSwitchLabel = uilabel(app.DAQPanel);
            app.AutoScaleYSwitchLabel.Enable = 'off';
            app.AutoScaleYSwitchLabel.Text = 'AutoScale Y';
            app.DAQSTARTButton = uibutton(app.DAQPanel, 'push');
            app.DAQSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @DAQSTARTButtonPushed, true);
            app.DAQSTARTButton.Enable = 'off';
            app.DAQSTARTButton.Text = 'DAQ START';
            app.DAQSTOPButton = uibutton(app.DAQPanel, 'push');
            app.DAQSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DAQSTOPButtonPushed, true);
            app.DAQSTOPButton.Enable = 'off';
            app.DAQSTOPButton.Text = 'DAQ STOP';
            app.StandAloneModeButton = uibutton(app.DAQPanel, 'state');
            app.StandAloneModeButton.ValueChangedFcn = createCallbackFcn(app, @StandAloneModeButtonValueChanged, true);
            app.StandAloneModeButton.Text = 'Stand Alone Mode';
            app.EXITButton = uibutton(app.DAQPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.DebugButton = uibutton(app.DAQPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.LIVEPlotPanel = uipanel(app.UIFigure);
            app.LIVEPlotPanel.Title = 'LIVE Plot';
            app.PupilCenterXY = uiaxes(app.LIVEPlotPanel);
            title(app.PupilCenterXY, 'Pupil Center (X, Y)')
            ylabel(app.PupilCenterXY, 'Position (V)')
            app.PupilCenterXY.XTick = [];
            app.PhotoSensor = uiaxes(app.LIVEPlotPanel);
            title(app.PhotoSensor, 'Photo Sensor')
            ylabel(app.PhotoSensor, 'Sensor (V)')
            app.PhotoSensor.YLim = [-0.01 0.12];
            app.PhotoSensor.XTick = [];
            app.RotaryEncoder = uiaxes(app.LIVEPlotPanel);
            title(app.RotaryEncoder, 'Rotary Encoder')
            ylabel(app.RotaryEncoder, 'Angle (deg)')
            app.RotaryEncoder.XTick = [];
            app.TriggerMonitor = uiaxes(app.LIVEPlotPanel);
            title(app.TriggerMonitor, 'Trigger Monitor')
            xlabel(app.TriggerMonitor, 'Time (sec)')
            ylabel(app.TriggerMonitor, 'TTL (V)')
            app.TriggerMonitor.YLim = [-0.1 6];
            app.TriggerMonitor.XTick = [];
            app.PlotRecorded = uiaxes(app.LIVEPlotPanel);
            title(app.PlotRecorded, 'Captured Trace')
            xlabel(app.PlotRecorded, 'Time (sec)')
            ylabel(app.PlotRecorded, 'Pupil XY')
            app.IMAQPanel = uipanel(app.UIFigure);
            app.IMAQPanel.Title = 'IMAQ';
            app.CameraUIAxes = uiaxes(app.IMAQPanel);
            app.CameraUIAxes.XLim = [1 192];
            app.CameraUIAxes.YLim = [1 168];
            app.CameraUIAxes.XTick = [];
            app.CameraUIAxes.YTick = [];
            app.CameraUIAxes.ZTick = [];
            app.CameraButton = uibutton(app.IMAQPanel, 'state');
            app.CameraButton.ValueChangedFcn = createCallbackFcn(app, @CameraButtonValueChanged, true);
            app.CameraButton.Text = 'Camera OFF';
            app.CameraPreviewButton = uibutton(app.IMAQPanel, 'state');
            app.CameraPreviewButton.ValueChangedFcn = createCallbackFcn(app, @CameraPreviewButtonValueChanged, true);
            app.CameraPreviewButton.Enable = 'off';
            app.CameraPreviewButton.Text = 'Preview';
            app.AdujstROIButton = uibutton(app.IMAQPanel, 'state');
            app.AdujstROIButton.ValueChangedFcn = createCallbackFcn(app, @AdujstROIButtonValueChanged, true);
            app.AdujstROIButton.Enable = 'off';
            app.AdujstROIButton.Text = 'Adujst ROI';
            app.CameraSave = uibutton(app.IMAQPanel, 'state');
            app.CameraSave.ValueChangedFcn = createCallbackFcn(app, @CameraSaveValueChanged, true);
            app.CameraSave.Enable = 'off';
            app.CameraSave.Text = 'SAVE CAM OFF';
            app.VideoCaptureDelay = uieditfield(app.IMAQPanel, 'numeric');
            app.VideoCaptureDelay.ValueDisplayFormat = '%.0f';
            app.VideoCaptureDelay.ValueChangedFcn = createCallbackFcn(app, @VideoCaptureDelayValueChanged, true);
            app.VideoCaptureDelay.Enable = 'off';
            app.CapturedurationmsLabel = uilabel(app.IMAQPanel);
            app.CapturedurationmsLabel.Text = 'Duration (ms)';
            app.VideoCaptureTime = uieditfield(app.IMAQPanel, 'numeric');
            app.VideoCaptureTime.ValueDisplayFormat = '%.0f';
            app.VideoCaptureTime.ValueChangedFcn = createCallbackFcn(app, @VideoCaptureTimeValueChanged, true);
            app.VideoCaptureTime.Enable = 'off';
            app.VideoCaptureTime.Value = 1000;
            app.DelaymsEditFieldLabel_2 = uilabel(app.IMAQPanel);
            app.DelaymsEditFieldLabel_2.Text = 'Delay (ms)';
            app.CapturedurationmsLabel_2 = uilabel(app.IMAQPanel);
            app.CapturedurationmsLabel_2.Text = 'Frame Rate (fps)';
            app.VideoFrameRate = uieditfield(app.IMAQPanel, 'numeric');
            app.VideoFrameRate.ValueDisplayFormat = '%.0f';
            app.VideoFrameRate.ValueChangedFcn = createCallbackFcn(app, @VideoFrameRateValueChanged, true);
            app.VideoFrameRate.Enable = 'off';
            app.VideoFrameRate.Value = 200;
            app.TTLAnalogOutputPanel = uipanel(app.UIFigure);
            app.TTLAnalogOutputPanel.Title = 'TTL -Analog Output';
            app.DelaymsEditFieldLabel = uilabel(app.TTLAnalogOutputPanel);
            app.DelaymsEditFieldLabel.Text = 'Delay (ms)';
            app.TTLSwitch = uibutton(app.TTLAnalogOutputPanel, 'state');
            app.TTLSwitch.ValueChangedFcn = createCallbackFcn(app, @TTLSwitchValueChanged, true);
            app.TTLSwitch.Text = 'TTL OFF';
            app.SetDurationDropDownLabel = uilabel(app.TTLAnalogOutputPanel);
            app.SetDurationDropDownLabel.Text = 'Set Duration';
            app.SetDurationDropDown = uidropdown(app.TTLAnalogOutputPanel);
            app.SetDurationDropDown.Items = {'Fix Duration', 'Fix Pulse Number'};
            app.SetDurationDropDown.ValueChangedFcn = createCallbackFcn(app, @SetDurationDropDownValueChanged, true);
            app.SetDurationDropDown.Enable = 'off';
            app.SetDurationDropDown.Value = 'Fix Duration';
            app.TTLDelay = uieditfield(app.TTLAnalogOutputPanel, 'numeric');
            app.TTLDelay.ValueDisplayFormat = '%.0f';
            app.TTLDelay.ValueChangedFcn = createCallbackFcn(app, @TTLDelayValueChanged, true);
            app.TTLDelay.Enable = 'off';
            app.TTLDelay.Value = 100;
            app.FreqencyHzLabel = uilabel(app.TTLAnalogOutputPanel);
            app.FreqencyHzLabel.Text = 'Frequency (Hz)';
            app.TTLFrequency = uieditfield(app.TTLAnalogOutputPanel, 'numeric');
            app.TTLFrequency.ValueDisplayFormat = '%.0f';
            app.TTLFrequency.ValueChangedFcn = createCallbackFcn(app, @TTLFrequencyValueChanged, true);
            app.TTLFrequency.Enable = 'off';
            app.TTLFrequency.Value = 100;
            app.TTLDutyCycle = uieditfield(app.TTLAnalogOutputPanel, 'numeric');
            app.TTLDutyCycle.ValueChangedFcn = createCallbackFcn(app, @TTLDutyCycleValueChanged, true);
            app.TTLDutyCycle.Enable = 'off';
            app.TTLDutyCycle.Value = 0.5;
            app.DurationmsLabel = uilabel(app.TTLAnalogOutputPanel);
            app.DurationmsLabel.Text = 'Duration (ms)';
            app.TTLDuration = uieditfield(app.TTLAnalogOutputPanel, 'numeric');
            app.TTLDuration.ValueDisplayFormat = '%.0f';
            app.TTLDuration.ValueChangedFcn = createCallbackFcn(app, @TTLDurationValueChanged, true);
            app.TTLDuration.Enable = 'off';
            app.TTLDuration.Value = 100;
            app.DutyCycleLabel = uilabel(app.TTLAnalogOutputPanel);
            app.DutyCycleLabel.Text = 'Duty Cycle';
            app.DurationmsLabel_2 = uilabel(app.TTLAnalogOutputPanel);
            app.DurationmsLabel_2.Text = 'Pulse Number';
            app.TTLPulseNum = uieditfield(app.TTLAnalogOutputPanel, 'numeric');
            app.TTLPulseNum.ValueDisplayFormat = '%.0f';
            app.TTLPulseNum.ValueChangedFcn = createCallbackFcn(app, @TTLPulseNumValueChanged, true);
            app.TTLPulseNum.Editable = 'off';
            app.TTLPulseNum.Enable = 'off';
            app.TTLPulseNum.Value = 100;
            app.SinglePulseWidthLabel = uilabel(app.TTLAnalogOutputPanel);
            app.SinglePulseWidthLabel.Enable = 'off';
            app.SinglePulseWidthLabel.Text = 'Single Pulse Width: ***** ms';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_daq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end