classdef BitPlayer_daq_original < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_DAQAppUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        AutoScaleYSwitch          matlab.ui.control.Switch
        AutoScaleYSwitchLabel     matlab.ui.control.Label
        LiveTime                  matlab.ui.control.NumericEditField
        LiveTimemsEditFieldLabel  matlab.ui.control.Label
        SamplingRate              matlab.ui.control.NumericEditField
        SamplingkHzLabel          matlab.ui.control.Label
        ITI                       matlab.ui.control.NumericEditField
        ITIsecEditFieldLabel      matlab.ui.control.Label
        RecTime                   matlab.ui.control.NumericEditField
        RecTimemsEditFieldLabel   matlab.ui.control.Label
        SaveasLabel               matlab.ui.control.Label
        FileNameLabel             matlab.ui.control.Label
        MouseIDLabel              matlab.ui.control.Label
        FileNameTxt               matlab.ui.control.Label
        EditFieldFileName         matlab.ui.control.EditField
        EditFieldMouseID          matlab.ui.control.EditField
        StandAloneModeButton      matlab.ui.control.StateButton
        StateText                 matlab.ui.control.Label
        SAVEOFFButton             matlab.ui.control.Button
        SAVEONButton              matlab.ui.control.Button
        DAQSTOPButton             matlab.ui.control.Button
        DAQSTARTButton            matlab.ui.control.Button
        CenterPanel               matlab.ui.container.Panel
        TriggerMonitor            matlab.ui.control.UIAxes
        PlotRecorded              matlab.ui.control.UIAxes
        RotaryEncoder             matlab.ui.control.UIAxes
        PhotoSensor               matlab.ui.control.UIAxes
        PupilCenterXY             matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        DebugButton               matlab.ui.control.Button
        EXITButton                matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % BitPlayer for daq controle.
    % 2023/01/04 version 0.1.2, GUI update
    % 2022/08/31 version 0.1, lattice
    % Refrence the Code for analog trigger and live plotting function 
    %%% Analog Trigger App example
    %%% 2019/01/11 version 1.0 Andrei Ursache
    %%% 2019/12/21 version 1.1, AU, updated DAQ code from Session to DataAcquisition interface

    properties (Access = public)
        recobj;                                % Struct for recording parameters
        c;                                         % Struct for capture configuration
        imaq;                                  % Struct for camera configuration
        d_in;                                    % daq interface for analog and counter inputs
        d_out;                                 % daq interface for digital output (use for TTL trigger)
        loopON = false;                 % Loop flag
        saveON = false;                 % Save flag
        plot1;
        plot2;
        plot3;
        plot4;
        plot5;
        DAQt;                                  % Timer from the first loop.
        TrigActive = false;               % Flag for triggered condition
        TrigMoment =[];                 % Timing when trigger signal reached
        CaptureStartMoment;         % Timestamp of trigger moment.
        DataFIFOBuffer;                  % Timestamps FIFO buffer for live plot (of latest N sec)
        TimestampsFIFOBuffer;       % Data FIFO buffer for live plot
        Data = [];                          % Latest data values used for trigger detection
        Timestamps = [];               % Latest timestomas used for trigger detection
        CaptureData;                     % Captured data
        CaptureTimestamps;          % Captured timestamps
        SaveData;
        SaveTimestamps;
        CurrentState = 'DAQstop';             %Current stat
        RTS = false;                        % serial connection RTS from PTB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Project path setting
            mypath = 'G:\マイドライブ\Research\BitPlayer\';
            cd(mypath)
            
            subfolders = {'DAQ', 'GUI' 'Common'};
            for i = 1:length(subfolders)
                p = genpath([mypath, subfolders{i}]);
                addpath(p)
            end
            % Add subfolders to path
            %addpath(genpath(mypath))

            % Version
            app.recobj.ver_BitPlayer ="0.1.1-2022/12/20";

            % Initialization
            recobj_ini(app);
            DAQ_ini(app);
            plot_ini(app);
            capture_ini(app); %DAQ capture settings
            stateMonitor(app);
            app.DAQSTARTButton.Enable = "on";
            
            %Generate Data fodler (named as current date)
            app.recobj.SaveDir = ...
                CreateDataFolder('C:\Users\lattice\Research\BitPlayerData\', 'daq');
            
        end

        % Callback function
        function DAQOFFButtonValueChanged(app, event)
            value = app.DAQOFFButton.Value;
            if value
                app.DAQOFFButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.DAQOFFButton.Text = "DAQ-ON";
                [app.s, app.sOut, app.dio, app.capture, app.dev] = daq_ini(1); %DAQ for iRecHS2
                
                %trigger state off(off:1, on:0)
                outputSingleScan(app.dio.VSon,1)
            else
                app.DAQOFFButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.DAQOFFButton.Text = "DAQ-OFF";
            end
        end

        % Callback function
        function SAVEButtonValueChanged(app, event)
            value = app.SAVEButton.Value;
            if value
                if app.loopON
                    app.SAVEButton.BackgroundColor = [1.0, 0.7, 0.7,];
                    app.SAVEButton.Text = "SAVING";
                else
                    app.SAVEButton.BackgroundColor = [0.6, 0.6, 0.6];
                    app.SAVEButton.Text = "SAVE-OFF";
                end
            else
                app.SAVEButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.SAVEButton.Text = "SAVE";
            end
            
        end

        % Callback function
        function STARTButtonValueChanged(app, event)
            value = app.DAQSTARTButton.Value;
            if value
                app.DAQSTARTButton.BackgroundColor = [0.0, 1.0, 0.0];
                app.DAQSTARTButton.Text = "Recording";
                loop_daq_test(app);
            else
                app.DAQSTARTButton.BackgroundColor = [1.0, 0.5, 0.5];
                app.DAQSTARTButton.Text = "START";
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_DAQAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {699, 699, 699};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {699, 699};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x', 336};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close all;
            app.delete;
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            assignin('base', "app", app)
        end

        % Callback function
        function STOPButtonValueChanged(app, event)
            %value = app.DAQSTOPButton.Value;
            app.DAQSTOPButton.Enable = "off";
            app.loopON = false;
            app.DAQSTARTButton.Enable = "on";
            disp('Stop DAQ, delete event listner')
            
        end

        % Button pushed function: DAQSTARTButton
        function DAQSTARTButtonPushed(app, event)
            app.DAQSTOPButton.Enable = "on";
            app.loopON = true;
            app.DAQSTARTButton.Enable = "off";

            % Reset FIFO buffer
            app.DataFIFOBuffer = [];
            app.TimestampsFIFOBuffer = [];

            % Reset Data and Timestamps
            app.Data = [];
            app.Timestamps = [];

            %%%%%%%%%%
            main_loop(app);

            %%%%%%%%%%

        end

        % Button pushed function: DAQSTOPButton
        function DAQSTOPButtonPushed(app, event)
            app.loopON = false;
            
            %
            stop(app.d_in);
            flush(app.d_in);
            app.DAQSTOPButton.Enable = "off";
            app.DAQSTARTButton.Enable = "on";

            app.SAVEOFFButton.Enable = "off";
            app.SAVEONButton.Enable = "on";

            app.CurrentState = 'DAQstop';
            app.CaptureData = [];
            app.CaptureTimestamps = [];
            stateMonitor(app)
            %}

        end

        % Button pushed function: SAVEONButton
        function SAVEONButtonPushed(app, event)

            if isempty(app.EditFieldFileName.Value) || isempty(app.EditFieldMouseID.Value)
                errordlg('Input Mouse ID or File Name, first!!!');
            else
                app.SAVEOFFButton.Enable = "on";
                app.saveON = true;
                app.SAVEONButton.Enable = "off";
                UpdateSaveFileName(app, app.EditFieldFileName.Value, 'daq')
            end
        end

        % Button pushed function: SAVEOFFButton
        function SAVEOFFButtonPushed(app, event)
            
            app.SAVEOFFButton.Enable = "off";
            app.saveON = false;
            app.SAVEONButton.Enable = "on";
        end

        % Value changed function: LiveTime
        function LiveTimeValueChanged(app, event)
            %value = app.LiveTime.Value;
            capture_ini(app);

        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            value = app.SamplingRate.Value;
            app.recobj.sampf = value;
            app.d_in.Rate = value;
            capture_init(app);
            
        end

        % Value changed function: RecTime
        function RecTimeValueChanged(app, event)
            value = app.RecTime.Value;
            app.recobj.rect = value;
            app.recobj.recp = app.recobj.sampf * value/1000;
            capture_ini(app);
            
        end

        % Value changed function: StandAloneModeButton
        function StandAloneModeButtonValueChanged(app, event)
            obj = app.StandAloneModeButton;
            val = obj.Value;
            if val
                obj.BackgroundColor = [1, 0, 0];
                obj.FontColor = [1, 1, 1];
            else 
                obj.BackgroundColor = [1, 0.82, 0.82];
                obj.FontColor = [0, 0, 0];
            end
            
        end

        % Value changed function: EditFieldMouseID
        function EditFieldMouseIDValueChanged(app, event)
            %value = app.EditFieldMouseID.Value;
            CreateMouseIDFolder(app, 'daq');
        end

        % Value changed function: EditFieldFileName
        function EditFieldFileNameValueChanged(app, event)
            value = app.EditFieldFileName.Value;
            UpdateSaveFileName(app, value, 'daq');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_DAQAppUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_DAQAppUIFigure.Name = 'Bit Player_DAQ.App';
            app.BitPlayer_DAQAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_DAQAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'DAQ';
            app.DAQSTARTButton = uibutton(app.LeftPanel, 'push');
            app.DAQSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @DAQSTARTButtonPushed, true);
            app.DAQSTARTButton.Enable = 'off';
            app.DAQSTARTButton.Text = 'DAQ START';
            app.DAQSTOPButton = uibutton(app.LeftPanel, 'push');
            app.DAQSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @DAQSTOPButtonPushed, true);
            app.DAQSTOPButton.Enable = 'off';
            app.DAQSTOPButton.Text = 'DAQ STOP';
            app.SAVEONButton = uibutton(app.LeftPanel, 'push');
            app.SAVEONButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEONButtonPushed, true);
            app.SAVEONButton.Text = 'SAVE-ON';
            app.SAVEOFFButton = uibutton(app.LeftPanel, 'push');
            app.SAVEOFFButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEOFFButtonPushed, true);
            app.SAVEOFFButton.Enable = 'off';
            app.SAVEOFFButton.Text = 'SAVE-OFF';
            app.StateText = uilabel(app.LeftPanel);
            app.StateText.Text = 'Starting up...';
            app.StandAloneModeButton = uibutton(app.LeftPanel, 'state');
            app.StandAloneModeButton.ValueChangedFcn = createCallbackFcn(app, @StandAloneModeButtonValueChanged, true);
            app.StandAloneModeButton.Text = 'Stand Alone Mode';
            app.EditFieldMouseID = uieditfield(app.LeftPanel, 'text');
            app.EditFieldMouseID.ValueChangedFcn = createCallbackFcn(app, @EditFieldMouseIDValueChanged, true);
            app.EditFieldMouseID.Placeholder = 'Mouse ID';
            app.EditFieldFileName = uieditfield(app.LeftPanel, 'text');
            app.EditFieldFileName.ValueChangedFcn = createCallbackFcn(app, @EditFieldFileNameValueChanged, true);
            app.EditFieldFileName.Placeholder = 'File Name';
            app.FileNameTxt = uilabel(app.LeftPanel);
            app.FileNameTxt.Text = '_*.mat';
            app.MouseIDLabel = uilabel(app.LeftPanel);
            app.MouseIDLabel.Text = 'Mouse ID:';
            app.FileNameLabel = uilabel(app.LeftPanel);
            app.FileNameLabel.Text = 'File Name:';
            app.SaveasLabel = uilabel(app.LeftPanel);
            app.SaveasLabel.Text = 'Save as:';
            app.RecTimemsEditFieldLabel = uilabel(app.LeftPanel);
            app.RecTimemsEditFieldLabel.Text = 'Rec Time (ms)';
            app.RecTime = uieditfield(app.LeftPanel, 'numeric');
            app.RecTime.ValueChangedFcn = createCallbackFcn(app, @RecTimeValueChanged, true);
            app.RecTime.Value = 3000;
            app.ITIsecEditFieldLabel = uilabel(app.LeftPanel);
            app.ITIsecEditFieldLabel.Text = 'ITI (sec)';
            app.ITI = uieditfield(app.LeftPanel, 'numeric');
            app.ITI.Value = 1;
            app.SamplingkHzLabel = uilabel(app.LeftPanel);
            app.SamplingkHzLabel.Text = 'Sampling (kHz)';
            app.SamplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.SamplingRate.Value = 15;
            app.LiveTimemsEditFieldLabel = uilabel(app.LeftPanel);
            app.LiveTimemsEditFieldLabel.Text = 'Live Time (ms)';
            app.LiveTime = uieditfield(app.LeftPanel, 'numeric');
            app.LiveTime.ValueChangedFcn = createCallbackFcn(app, @LiveTimeValueChanged, true);
            app.LiveTime.Value = 500;
            app.AutoScaleYSwitchLabel = uilabel(app.LeftPanel);
            app.AutoScaleYSwitchLabel.Enable = 'off';
            app.AutoScaleYSwitchLabel.Text = 'AutoScale Y';
            app.AutoScaleYSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AutoScaleYSwitch.Enable = 'off';
            app.AutoScaleYSwitch.Value = 'On';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Live Plots';
            app.PupilCenterXY = uiaxes(app.CenterPanel);
            title(app.PupilCenterXY, 'Pupil Center (X, Y)')
            ylabel(app.PupilCenterXY, 'Position (V)')
            app.PupilCenterXY.XTick = [];
            app.PhotoSensor = uiaxes(app.CenterPanel);
            title(app.PhotoSensor, 'Photo Sensor')
            ylabel(app.PhotoSensor, 'Sensor (V)')
            app.PhotoSensor.YLim = [-0.01 0.2];
            app.PhotoSensor.XTick = [];
            app.RotaryEncoder = uiaxes(app.CenterPanel);
            title(app.RotaryEncoder, 'Rotary Encoder')
            ylabel(app.RotaryEncoder, 'Angle (deg)')
            app.RotaryEncoder.XTick = [];
            app.PlotRecorded = uiaxes(app.CenterPanel);
            title(app.PlotRecorded, 'Recorded')
            xlabel(app.PlotRecorded, 'Time (sec)')
            ylabel(app.PlotRecorded, 'Pupil XY')
            app.TriggerMonitor = uiaxes(app.CenterPanel);
            title(app.TriggerMonitor, 'Trigger Monitor')
            xlabel(app.TriggerMonitor, 'Time (sec)')
            ylabel(app.TriggerMonitor, 'TTL (V)')
            app.TriggerMonitor.YLim = [-0.1 5.5];
            app.TriggerMonitor.XTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'IMAQ';
            app.Image = uiimage(app.RightPanel);
            app.EXITButton = uibutton(app.RightPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.DebugButton = uibutton(app.RightPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            % Show the figure after all components are created
            app.BitPlayer_DAQAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_daq_original

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BitPlayer_DAQAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_DAQAppUIFigure)
        end
    end
end