classdef BitPlayer_ptb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_PTBUIFigure          matlab.ui.Figure
        StiminfoTextAreaLabel          matlab.ui.control.Label
        DebugButton                    matlab.ui.control.Button
        StandAloneModeButton           matlab.ui.control.StateButton
        EXITButton                     matlab.ui.control.Button
        ClosePTBButton                 matlab.ui.control.StateButton
        OpenPTBButton                  matlab.ui.control.StateButton
        TTLfromDAQLamp                 matlab.ui.control.Lamp
        StiminfoTextArea               matlab.ui.control.TextArea
        VisualStim1Panel               matlab.ui.container.Panel
        FlashButton                    matlab.ui.control.StateButton
        CheckerboardButton             matlab.ui.control.StateButton
        LuminanceEditField             matlab.ui.control.NumericEditField
        LuminanceEditFieldLabel        matlab.ui.control.Label
        CheckerDivLabel                matlab.ui.control.Label
        CheckerDivDropDown             matlab.ui.control.DropDown
        DivideLabel                    matlab.ui.control.Label
        Divide                         matlab.ui.control.NumericEditField
        DistanceLabel                  matlab.ui.control.Label
        Distance                       matlab.ui.control.NumericEditField
        Coherence_Mode                 matlab.ui.control.StateButton
        Coherence                      matlab.ui.control.DropDown
        MoovingSpdLabel_2              matlab.ui.control.Label
        BarHeight                      matlab.ui.control.Label
        ISIEditFieldLabel              matlab.ui.control.Label
        BarOrientation                 matlab.ui.control.DropDown
        ConcentricDirection_Label      matlab.ui.control.Label
        ConcentricDirection            matlab.ui.control.DropDown
        DotDensity_Label               matlab.ui.control.Label
        MoveSpd                        matlab.ui.control.DropDown
        DotDensity                     matlab.ui.control.NumericEditField
        LoomingMax_Label               matlab.ui.control.Label
        NumImages                      matlab.ui.control.NumericEditField
        LoomingSpd_Label               matlab.ui.control.Label
        LoomingMax                     matlab.ui.control.NumericEditField
        BarOrientation_Label           matlab.ui.control.Label
        LoomingSpd                     matlab.ui.control.DropDown
        SpatialFreq_Label              matlab.ui.control.Label
        SpatialFreq                    matlab.ui.control.DropDown
        TemporalFreq_Label             matlab.ui.control.Label
        TemporalFreq                   matlab.ui.control.DropDown
        MoveSpd_Label                  matlab.ui.control.Label
        Direction                      matlab.ui.control.DropDown
        Direction_Label                matlab.ui.control.Label
        GetFinePos                     matlab.ui.control.Button
        Distance_unit                  matlab.ui.control.Label
        FixedPos                       matlab.ui.control.NumericEditField
        FixedPos_Label                 matlab.ui.control.Label
        MonitorDistance                matlab.ui.control.NumericEditField
        MonitorDiv_Label               matlab.ui.control.Label
        MonitorDiv                     matlab.ui.control.NumericEditField
        Size_Label                     matlab.ui.control.Label
        Blankloop                      matlab.ui.control.NumericEditField
        DelayEditField_2Label_2        matlab.ui.control.Label
        BlankloopLabel                 matlab.ui.control.Label
        ISI                            matlab.ui.control.NumericEditField
        ISI_Label                      matlab.ui.control.Label
        Size                           matlab.ui.control.NumericEditField
        Delay_Label                    matlab.ui.control.Label
        Delay                          matlab.ui.control.NumericEditField
        Duration_Label                 matlab.ui.control.Label
        DurationMoveStim_Label         matlab.ui.control.Label
        BackgroundEditField            matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel       matlab.ui.control.Label
        Duration                       matlab.ui.control.NumericEditField
        MonitorDistance_Label          matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        PatternDropDown                matlab.ui.control.DropDown
        PositionOrderDropDown          matlab.ui.control.DropDown
        ShiftDirectionLabel            matlab.ui.control.Label
        ConcentricDirectionLabel       matlab.ui.control.Label
        MoovingSpdLabel                matlab.ui.control.Label
        MonitorDistanceEditFieldLabel  matlab.ui.control.Label
        DotDensityLabel                matlab.ui.control.Label
        NumberofImagesEditFieldLabel   matlab.ui.control.Label
        LoomingMaxEditFieldLabel       matlab.ui.control.Label
        LoomingSpdDropDownLabel        matlab.ui.control.Label
        SpatialFreqDropDownLabel       matlab.ui.control.Label
        TemporalFreqDropDownLabel      matlab.ui.control.Label
        MovingDirectionLabel           matlab.ui.control.Label
        FixedPositionEditFieldLabel    matlab.ui.control.Label
        MonitorDivisionEditFieldLabel  matlab.ui.control.Label
        SizeDiameterEditFieldLabel     matlab.ui.control.Label
        DelayEditField_2Label          matlab.ui.control.Label
        DurationEditFieldLabel         matlab.ui.control.Label
        ShapeDropDownLabel             matlab.ui.control.Label
        PatternDropDownLabel           matlab.ui.control.Label
        PositionOrderDropDownLabel     matlab.ui.control.Label
        FileNameTxt                    matlab.ui.control.Label
        EditFieldFileName              matlab.ui.control.EditField
        EditFieldMouseID               matlab.ui.control.EditField
        PTBSTOPButton                  matlab.ui.control.Button
        PTBSTARTButton                 matlab.ui.control.Button
        SAVEOFFButton                  matlab.ui.control.Button
        SAVEONButton                   matlab.ui.control.StateButton
        TTLfromDAQLampLabel            matlab.ui.control.Label
    end

    % Properties
    properties (Access = public)
        n_in_loop   % Trial number
        
        imgobj      % imaging parameters 
        mainvar     % data paths
        ParamsSave  % visual stim parameters in each trial
        DataSave    % loaded Data <- need this properties?
        sobj        % Stimlus Parameters
        SerPort     % serial port 
        loopON = false;
        saveON = false;
    end
    
    properties (Access = private)
        GetFinePosApp % 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %add subfolders
            %mypath = '/home/lattice/GoogleDrive/Research/BitPlayer/PTB/';
            mypath = '/home/lattice/Research/BitPlayer/PTB/';
            cd(mypath)
            addpath("../Common")
            subfos = {'GUI', 'CheckParams', 'Stim'};
            for i = 1:length(subfos)
                p = genpath([mypath, subfos{i}]);
                addpath(p);
            end
            
            %Initialize stimulu parameters.
            app.sobj = sobj_ini;

            %Open PTB
            OpenPTBWindow(app);
            app.OpenPTBButton.Enable = 'off';
            gui_ini(app);
            Set_StimPattern(app);
            
            %Create data folder
            app.sobj.SaveDir = ...
                CreateDataFolder('/home/lattice/Research/BitPlayerData/','ptb');

            %Create serial port
            app.SerPort = serialport('/dev/ttyUSB0', 9600);
            %RTS true: ready to start the next stimulus
            %RTS false: "Out of loop" or "Presenting a stimulus"

            setRTS(app.SerPort, false) %false: -10V=> low TTL
            
        end

        % Value changed function: PatternDropDown
        function PatternDropDownValueChanged(app, event)
            p = app.PatternDropDown.Value;
            app.sobj.Pattern = p;
            Set_StimPattern(app);
            switch p
                case {'Fine Mapping', 'Search V1_Fine'}
                    app.DistanceLabel.Text = 'Fine map size';
                    app.PositionOrderDropDown.Items = {'Random Matrix',...
                        'Ordered Matrix', 'Fix Repeat'};
                    app.PositionOrderDropDown.Value = 'Random Matrix';

                case {'Fine Mapping Free'}
                    app.PositionOrderDropDown.Items = {'Random'};
                    app.PositionOrderDropDown.Value = 'Random';
                    app.DistanceLabel.Text = 'Fine map size';

                case {'Random Dot Motion', 'Image Presentation'}
                    app.DistanceLabel.Text = 'Patch size';
                    app.PositionOrderDropDown.Items = {'Random Matrix',...
                        'Ordered Matrix', 'Fix Repeat'};
                    app.PositionOrderDropDown.Value = 'Fix Repeat';

                case 'Search V1_Coarse'
                    app.PositionOrderDropDown.Items = {'Random Matrix',...
                        'Ordered Matrix', 'Fix Repeat'};
                    app.PositionOrderDropDown.Value = 'Ordered Matrix';

                case {'Static Bar', 'Size Random', 'Moving spot'}
                    app.PositionOrderDropDown.Items = {'Random Matrix',...
                        'Ordered Matrix', 'Fix Repeat'};
                    app.PositionOrderDropDown.Value = 'Fix Repeat';

                case {'Decode SC', 'Decode test'}
                    app.DistanceLabel.Text = 'Checker Area';
                    app.PositionOrderDropDown.Value = 'Fix Repeat';

                case {'ImageNet train', 'ImageNet test','ImageNet NoiseCorr'}
                    app.DistanceLabel.Text = 'Image Size';
                    app.PositionOrderDropDown.Value = 'Fix Repeat';

                otherwise
                    app.PositionOrderDropDown.Items = {'Random Matrix',...
                        'Ordered Matrix', 'Fix Repeat'};
                    app.DistanceLabel.Text = 'Distance';
            end


            %%% Set Bar width %%%%%%%%%%
            switch p
                case {'Moving Bar', 'Static Bar'}
                    app.BarHeight.Visible = 'on';
                    %app.BarHeight.Text = 'Height: 65 deg';
                otherwise
                    app.BarHeight.Visible = 'off';
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%% Reset size
            value = app.Size.Value;
            stimsz = round(ones(1,2) * Deg2Pix(value, app.sobj.MonitorDist, app.sobj.Pixelpitch));
            app.sobj.StimSize_pix = stimsz;
            app.sobj.StimSize_deg = value;

            %
            Check_Stim_Duration(app);

        end

        % Value changed function: PositionOrderDropDown
        function PositionOrderDropDownValueChanged(app, event)
            %value = app.PositionOrderDropDown.Value;
            Set_StimPattern(app);
            app.sobj.PositionMode = app.PositionOrderDropDown.Value;

        end

        % Value changed function: MonitorDistance
        function MonitorDistanceValueChanged(app, event)
            value = app.MonitorDistance.Value;
            app.sobj.MonitorDist = value;
            
            %Change stimulus size in pix
            app.sobj.StimSize_pix = round(...
                ones(1,2) * Deg2Pix(app.sobj.StimSize_deg, value, app.sobj.Pixelpitch)...
                );
            app.sobj.Bar_width_pix = app.sobj.StimSize_pix(1);
            %Bar length (fixed 65 deg) also depends on MonitorDist/Pixelpitch,
            %so keep it in sync here the same way Bar_width_pix is.
            app.sobj.Bar_heigth_pix = round(Deg2Pix(app.sobj.Bar_height, value, app.sobj.Pixelpitch));

            
            %Check Stim Area
            Check_StimeArea_Distance(app);
            %Stim duration
            Check_Stim_Duration(app);

        end

        % Value changed function: Duration
        function DurationValueChanged(app, event)
            value = app.Duration.Value;
            app.sobj.FlipNum = round(value * app.sobj.FrameRate);
            app.Duration_Label.Text = ['sec = ', num2str(app.sobj.FlipNum), ' flips'];
            app.sobj.Duration_sec = value;
        end

        % Value changed function: Delay
        function DelayValueChanged(app, event)
            value = app.Delay.Value;
            app.sobj.Delay_FlipNum = round(value * app.sobj.FrameRate);
            app.Delay_Label.Text = ['sec = ', num2str(app.sobj.Delay_FlipNum), ' flips'];
            app.sobj.Delay_sec = value;
            
        end

        % Value changed function: Size
        function SizeValueChanged(app, event)
            SizeChange(app, app.Size.Value);
%             value = app.Size.Value;
%             stimsz = round(ones(1,2) * Deg2Pix(value, app.sobj.MonitorDist, app.sobj.Pixelpitch));
%             app.sobj.StimSize_pix = stimsz;
%             app.sobj.Bar_width_pix = stimsz(1);
%             app.sobj.StimSize_deg = value;
%             app.sobj.Bar_width = value(1);
            
        end

        % Value changed function: MonitorDiv
        function MonitorDivValueChanged(app, event)
            value = app.MonitorDiv.Value;
            app.sobj.DivNum = value;
            app.MonitorDiv_Label.Text = [num2str(value), 'x', num2str(value), ' Matrix'];
            app.FixedPos_Label.Text = ['in ', num2str(value), 'x', num2str(value), ' Matrix'];
            
            
        end

        % Value changed function: FixedPos
        function FixedPosValueChanged(app, event)
            %value = app.FixedPos.Value;
            app.sobj.FixPos = app.FixedPos.Value;
            app.FixedPos_Label.Text = ['in ', num2str(app.sobj.DivNum), 'x', num2str(app.sobj.DivNum), ' Matrix'];
            
        end

        % Value changed function: Direction
        function DirectionValueChanged(app, event)
%             app.sobj.MoveDirection_type = app.Direction.Value;

%             if ~contains(value, {'Rand', 'Ord'})
%                 app.sobj.MoveDirection = str2double(value);
%             end
%             app.sobj.MoveDirection_i = find(strcmp(app.Direction.Items, value));
%             
        end

        % Value changed function: MoveSpd
        function MoveSpdValueChanged(app, event)
            value = app.MoveSpd.Value;
            app.sobj.MoveSpd = str2double(value);
            app.sobj.MoveSpd_i = find(strcmp(app.MoveSpd.Items, value));
            Check_Stim_Duration(app);
            
        end

        % Value changed function: TemporalFreq
        function TemporalFreqValueChanged(app, event)
            value = app.TemporalFreq.Value;
            app.sobj.TemporalFreq = str2double(value);
            app.sobj.TemporalFreq_i = find(strcmp(app.TemporalFreq.Items, value));
            
        end

        % Value changed function: SpatialFreq
        function SpatialFreqValueChanged(app, event)
            value = app.SpatialFreq.Value;
            app.sobj.SpatialFreq = str2double(value);
            app.sobj.SpatialFreq_i = find(strcmp(app.SpatialFreq.Items, value));
            
        end

        % Value changed function: LoomingSpd
        function LoomingSpdValueChanged(app, event)
            value = app.LoomingSpd.Value;
            app.sobj.LoomingSpd = str2double(value);
            app.sobj.LoomingSpd_i = find(strcmp(app.LoomingSpd.Items, value));
            Check_Stim_Duration(app);
            
        end

        % Value changed function: LoomingMax
        function LoomingMaxValueChanged(app, event)
            value = app.LoomingMax.Value;
            app.sobj.LoomingMaxSize = value;
            Check_Stim_Duration(app);
            
        end

        % Value changed function: NumImages
        function NumImagesValueChanged(app, event)
            value = app.NumImages.Value;
            app.sobj.ImageNum = value;
            
        end

        % Value changed function: DotDensity
        function DotDensityValueChanged(app, event)
            value = app.DotDensity.Value;
            app.sobj.DotsDensity = value /100;
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            disp('Debug')
            assignin('base', 'sobj', app.sobj);
            assignin('base', 'ParamsSave', app.ParamsSave);
            %assignin('base', 'sport', app.SerPort);
            %assignin('base', 'textinfo', app.StiminfoTextArea.Value);
        end

        % Value changed function: ConcentricDirection
        function ConcentricDirectionValueChanged(app, event)
            value = app.ConcentricDirection.Value;
            app.sobj.ConcentricDirection_i = find(strcmp(app.ConcentricDirection.Items, value));
            
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            value = app.ShapeDropDown.Value;
            if strcmp(value, 'Circle')
                app.sobj.Shape = 'FillOval';
            elseif strcmp(value, 'Square')
                app.sobj.Shape = 'FillRect';
            end
        end

        % Value changed function: OpenPTBButton
        function OpenPTBButtonValueChanged(app, event)
            %value = app.OpenPTBButton.Value;
            OpenPTBWindow(app)
            app.OpenPTBButton.Enable = 'off';
            app.ClosePTBButton.Enable = 'on';
        end

        % Value changed function: ClosePTBButton
        function ClosePTBButtonValueChanged(app, event)

            %Delete serial port.
            app.SerPort =[];
            Screen('Close')
            app.OpenPTBButton.Enable = 'on';
            app.ClosePTBButton.Enable = 'off';
        end

        % Button pushed function: PTBSTARTButton
        function PTBSTARTButtonPushed(app, event)
            
            app.PTBSTARTButton.Enable = "off";
            app.PTBSTOPButton.Enable = "on";
            app.SAVEOFFButton.Enable = "off";
            app.loopON = true;

            %true: Ready to start next stim.
            setRTS(app.SerPort, true);
            MainLoop(app)
        end

        % Button pushed function: PTBSTOPButton
        function PTBSTOPButtonPushed(app, event)
            
            app.PTBSTOPButton.Enable = "off";
            app.PTBSTARTButton.Enable = "on";
            app.loopON = false;

            %false: Out of loop
            setRTS(app.SerPort, false);
            ChangeGUI_StartPTB(app.PTBSTARTButton, 'finish');
        end

        % Value changed function: SAVEONButton
        function SAVEONButtonValueChanged(app, event)

            if isempty(app.EditFieldFileName.Value) || isempty(app.EditFieldMouseID.Value)
                app.SAVEONButton.Value = false;
                errordlg('Input mouseID or Prefix, first!!!')
            else

                app.SAVEONButton.Enable = "off";
                app.SAVEONButton.Value = true;
                app.SAVEOFFButton.Enable = "on";
                UpdateSaveFileName(app, app.EditFieldFileName.Value,'ptb')
            end


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            %Delete serial port.
            app.SerPort =[];
            Screen('Close');
            close all;
            app.delete;
        end

        % Value changed function: EditFieldMouseID
        function EditFieldMouseIDValueChanged(app, event)
            %value = app.EditFieldMouseID.Value;
            app.sobj.SaveDirMouse = ...
                CreateMouseIDFolder(app, 'ptb');
        end

        % Value changed function: EditFieldFileName
        function EditFieldFileNameValueChanged(app, event)
            value = app.EditFieldFileName.Value;
            UpdateSaveFileName(app, value, 'ptb');
        end

        % Value changed function: StandAloneModeButton
        function StandAloneModeButtonValueChanged(app, event)
            obj = app.StandAloneModeButton;
            value = obj.Value;
            if value
                obj.BackgroundColor = [1,0,0];
                obj.FontColor = [1,1,1];
            else
                obj.BackgroundColor = [1,0.82,0.82];
                obj.FontColor = [0,0,0];
            end
        end

        % Button pushed function: SAVEOFFButton
        function SAVEOFFButtonPushed(app, event)
            app.SAVEONButton.Enable = "on";
            app.SAVEONButton.Value = false;
            app.SAVEOFFButton.Enable = "off";
        end

        % Value changed function: BackgroundEditField
        function BackgroundEditFieldValueChanged(app, event)
            % 0-255
            app.sobj.bgcol = app.BackgroundEditField.Value;
            %convet to 0-1
            %app.sobj.bgcol = app.sobj.bgcol/255;
            
        end

        % Value changed function: ISI
        function ISIValueChanged(app, event)
            app.sobj.ISI_sec = app.ISI.Value;
            Check_Stim_Duration(app);
        end

        % Value changed function: Blankloop
        function BlankloopValueChanged(app, event)
            app.sobj.Blankloop_times = app.Blankloop.Value;
            Check_Stim_Duration(app);

        end

        % Value changed function: Coherence_Mode
        function Coherence_ModeValueChanged(app, event)
            value = app.Coherence_Mode.Value;
            if value
                app.Coherence_Mode.Text = 'Hard';
                app.Coherence.Items = ...
                    {'Random', '1%', '3%', '5%', '10%', '30%', '50%', '70%'};
                
            else
                app.Coherence_Mode.Text = 'Easy';
                app.Coherence.Items = ...
                    {'Random', '10%', '20%', '30%', '60%', '80%', '98%'};
            end
            app.Coherence.Value = app.Coherence.Items(1);
        end

        % Value changed function: Divide
        function DivideValueChanged(app, event)

            app.sobj.Div_grid = app.Divide.Value;

        end

        % Value changed function: Distance
        function DistanceValueChanged(app, event)
            % 2026-09-08: unconditionally sync sobj.Distance/Div_grid via
            % Check_StimeArea_Distance for every pattern (previously only
            % ran for a few Decode SC/ImageNet patterns, so e.g. 'Fine
            % Mapping' never picked up GUI Distance changes -- see
            % DecodeSC_sub-analysys repo docs/THESIS_PLAN.md for the bug
            % history). All pattern-specific logic now lives in that .m
            % file so this callback should not need further edits.
            Check_StimeArea_Distance(app);
            Check_Stim_Duration(app);
        end

        % Button pushed function: GetFinePos
        function GetFinePosButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.GetFinePos.Enable = "off";
            % Open app
            app.GetFinePosApp = TransformFinePosition(app);
        end

        % Close request function: BitPlayer_PTBUIFigure
        function BitPlayer_PTBUIFigureCloseRequest(app, event)
            delete(app.GetFinePosApp);
            delete(app)
            
        end

        % Value changed function: LuminanceEditField
        function LuminanceEditFieldValueChanged(app, event)
            
            val = app.LuminanceEditField.Value;
            if val < 1 || val > 255
                app.LuminanceEditField.Value = 255;
                errordlg("Stim luminace shoud be in range of 0-255.")
            else
                app.sobj.stimlumi = val;
            end
            app.sobj.stimColor = app.sobj.stimlumi * app.sobj.stimRGB;

%             app.sobj.stimColor =...
%                 double(app.sobj.stimlumi)/255 * app.sobj.stimRGB;
        end

        % Value changed function: CheckerboardButton
        function CheckerboardButtonValueChanged(app, event)
            value = app.CheckerboardButton.Value;
            if value
                app.sobj.CheckerPatch = true;
            else
                app.sobj.CheckerPatch = false;
            end
        end

        % Value changed function: CheckerDivDropDown
        function CheckerDivDropDownValueChanged(app, event)

            app.sobj.Div_grid = str2double(app.CheckerDivDropDown.Value);
            Set_RandChecker(app);
            Load_test_images(app);
        end

        % Value changed function: FlashButton
        function FlashButtonValueChanged(app, event)
            app.sobj.FlashButton = app.FlashButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_PTBUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_PTBUIFigure.Name = 'BitPlayer_PTB';
            app.BitPlayer_PTBUIFigure.CloseRequestFcn = createCallbackFcn(app, @BitPlayer_PTBUIFigureCloseRequest, true);
            app.TTLfromDAQLampLabel = uilabel(app.BitPlayer_PTBUIFigure);
            app.TTLfromDAQLampLabel.Text = 'TTL from DAQ';
            app.SAVEONButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.SAVEONButton.ValueChangedFcn = createCallbackFcn(app, @SAVEONButtonValueChanged, true);
            app.SAVEONButton.Text = 'SAVE-ON';
            app.SAVEOFFButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.SAVEOFFButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEOFFButtonPushed, true);
            app.SAVEOFFButton.Enable = 'off';
            app.SAVEOFFButton.Text = 'SAVE-OFF';
            app.PTBSTARTButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.PTBSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @PTBSTARTButtonPushed, true);
            app.PTBSTARTButton.Text = 'PTB START';
            app.PTBSTOPButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.PTBSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @PTBSTOPButtonPushed, true);
            app.PTBSTOPButton.Enable = 'off';
            app.PTBSTOPButton.Text = 'PTB STOP';
            app.EditFieldMouseID = uieditfield(app.BitPlayer_PTBUIFigure, 'text');
            app.EditFieldMouseID.ValueChangedFcn = createCallbackFcn(app, @EditFieldMouseIDValueChanged, true);
            app.EditFieldMouseID.Placeholder = 'Mouse ID';
            app.EditFieldFileName = uieditfield(app.BitPlayer_PTBUIFigure, 'text');
            app.EditFieldFileName.ValueChangedFcn = createCallbackFcn(app, @EditFieldFileNameValueChanged, true);
            app.EditFieldFileName.Placeholder = 'File Name';
            app.EditFieldFileName.Value = 'ptb';
            app.FileNameTxt = uilabel(app.BitPlayer_PTBUIFigure);
            app.FileNameTxt.Text = '_*.mat';
            app.VisualStim1Panel = uipanel(app.BitPlayer_PTBUIFigure);
            app.VisualStim1Panel.Title = 'Visual Stim 1';
            app.PositionOrderDropDownLabel = uilabel(app.VisualStim1Panel);
            app.PositionOrderDropDownLabel.Text = 'Position Order';
            app.PatternDropDownLabel = uilabel(app.VisualStim1Panel);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.ShapeDropDownLabel = uilabel(app.VisualStim1Panel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.DurationEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.DurationEditFieldLabel.Text = 'Duration ';
            app.DelayEditField_2Label = uilabel(app.VisualStim1Panel);
            app.DelayEditField_2Label.Text = 'Delay';
            app.SizeDiameterEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.SizeDiameterEditFieldLabel.Text = 'Size (Diameter)';
            app.MonitorDivisionEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.MonitorDivisionEditFieldLabel.Text = 'Monitor  Division';
            app.FixedPositionEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.FixedPositionEditFieldLabel.Text = 'Fixed Position';
            app.MovingDirectionLabel = uilabel(app.VisualStim1Panel);
            app.MovingDirectionLabel.Text = 'Moving Direction';
            app.TemporalFreqDropDownLabel = uilabel(app.VisualStim1Panel);
            app.TemporalFreqDropDownLabel.Text = 'Temporal Freq.';
            app.SpatialFreqDropDownLabel = uilabel(app.VisualStim1Panel);
            app.SpatialFreqDropDownLabel.Text = 'Spatial Freq.';
            app.LoomingSpdDropDownLabel = uilabel(app.VisualStim1Panel);
            app.LoomingSpdDropDownLabel.Text = 'Looming Spd.';
            app.LoomingMaxEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.LoomingMaxEditFieldLabel.Text = 'Looming Max';
            app.NumberofImagesEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.DotDensityLabel = uilabel(app.VisualStim1Panel);
            app.DotDensityLabel.Text = 'Dot Density';
            app.MonitorDistanceEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.MonitorDistanceEditFieldLabel.Text = 'Monitor Distance';
            app.MoovingSpdLabel = uilabel(app.VisualStim1Panel);
            app.MoovingSpdLabel.Text = 'Mooving Spd.';
            app.ConcentricDirectionLabel = uilabel(app.VisualStim1Panel);
            app.ConcentricDirectionLabel.Text = 'Concentric Direction';
            app.ShiftDirectionLabel = uilabel(app.VisualStim1Panel);
            app.ShiftDirectionLabel.Text = 'Bar Orientation';
            app.PositionOrderDropDown = uidropdown(app.VisualStim1Panel);
            app.PositionOrderDropDown.Items = {'Random Matrix', 'Ordered Matrix', 'Fix Repeat', 'Concentric'};
            app.PositionOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionOrderDropDownValueChanged, true);
            app.PositionOrderDropDown.Value = 'Random Matrix';
            app.PatternDropDown = uidropdown(app.VisualStim1Panel);
            app.PatternDropDown.Items = {'Uni', 'Fine Mapping'};
            app.PatternDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternDropDownValueChanged, true);
            app.PatternDropDown.Value = 'Uni';
            app.ShapeDropDown = uidropdown(app.VisualStim1Panel);
            app.ShapeDropDown.Items = {'Circle', 'Square'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Circle';
            app.MonitorDistance_Label = uilabel(app.VisualStim1Panel);
            app.MonitorDistance_Label.Text = 'mm';
            app.Duration = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Duration.ValueChangedFcn = createCallbackFcn(app, @DurationValueChanged, true);
            app.BackgroundEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.VisualStim1Panel, 'numeric');
            app.BackgroundEditField.Limits = [0 255];
            app.BackgroundEditField.ValueChangedFcn = createCallbackFcn(app, @BackgroundEditFieldValueChanged, true);
            app.DurationMoveStim_Label = uilabel(app.VisualStim1Panel);
            app.DurationMoveStim_Label.Text = 'Duration in Moving Stim: ***** sec';
            app.Duration_Label = uilabel(app.VisualStim1Panel);
            app.Duration_Label.Text = 'sec = **** flips';
            app.Delay = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Delay.ValueChangedFcn = createCallbackFcn(app, @DelayValueChanged, true);
            app.Delay_Label = uilabel(app.VisualStim1Panel);
            app.Delay_Label.Text = 'sec = **** flips';
            app.Size = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Size.ValueChangedFcn = createCallbackFcn(app, @SizeValueChanged, true);
            app.ISI_Label = uilabel(app.VisualStim1Panel);
            app.ISI_Label.Text = 'sec';
            app.ISI = uieditfield(app.VisualStim1Panel, 'numeric');
            app.ISI.ValueChangedFcn = createCallbackFcn(app, @ISIValueChanged, true);
            app.BlankloopLabel = uilabel(app.VisualStim1Panel);
            app.BlankloopLabel.Text = 'loops = **** sec';
            app.DelayEditField_2Label_2 = uilabel(app.VisualStim1Panel);
            app.DelayEditField_2Label_2.Text = 'Blank loop';
            app.Blankloop = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Blankloop.ValueChangedFcn = createCallbackFcn(app, @BlankloopValueChanged, true);
            app.Blankloop.Value = 2;
            app.Size_Label = uilabel(app.VisualStim1Panel);
            app.Size_Label.Text = 'deg';
            app.MonitorDiv = uieditfield(app.VisualStim1Panel, 'numeric');
            app.MonitorDiv.ValueChangedFcn = createCallbackFcn(app, @MonitorDivValueChanged, true);
            app.MonitorDiv_Label = uilabel(app.VisualStim1Panel);
            app.MonitorDiv_Label.Text = '(* x * Matrix)';
            app.MonitorDistance = uieditfield(app.VisualStim1Panel, 'numeric');
            app.MonitorDistance.ValueChangedFcn = createCallbackFcn(app, @MonitorDistanceValueChanged, true);
            app.MonitorDistance.Value = 200;
            app.FixedPos_Label = uilabel(app.VisualStim1Panel);
            app.FixedPos_Label.Text = '(in * x * Matrix)';
            app.FixedPos = uieditfield(app.VisualStim1Panel, 'numeric');
            app.FixedPos.ValueDisplayFormat = '%11.6g';
            app.FixedPos.ValueChangedFcn = createCallbackFcn(app, @FixedPosValueChanged, true);
            app.Distance_unit = uilabel(app.VisualStim1Panel);
            app.Distance_unit.Text = 'deg';
            app.GetFinePos = uibutton(app.VisualStim1Panel, 'push');
            app.GetFinePos.ButtonPushedFcn = createCallbackFcn(app, @GetFinePosButtonPushed, true);
            app.GetFinePos.Text = 'Get Fine Position';
            app.Direction_Label = uilabel(app.VisualStim1Panel);
            app.Direction_Label.Text = 'deg (0: => Rightward)';
            app.Direction = uidropdown(app.VisualStim1Panel);
            app.Direction.Items = {'0'};
            app.Direction.ValueChangedFcn = createCallbackFcn(app, @DirectionValueChanged, true);
            app.Direction.Value = '0';
            app.MoveSpd_Label = uilabel(app.VisualStim1Panel);
            app.MoveSpd_Label.Text = 'deg/sec';
            app.TemporalFreq = uidropdown(app.VisualStim1Panel);
            app.TemporalFreq.Items = {'0.5', '1', '2', '4', '8'};
            app.TemporalFreq.ValueChangedFcn = createCallbackFcn(app, @TemporalFreqValueChanged, true);
            app.TemporalFreq.Value = '2';
            app.TemporalFreq_Label = uilabel(app.VisualStim1Panel);
            app.TemporalFreq_Label.Text = 'Hz';
            app.SpatialFreq = uidropdown(app.VisualStim1Panel);
            app.SpatialFreq.Items = {'0.01', '0.02', '0.04', '0.08', '0.16', '0.32'};
            app.SpatialFreq.ValueChangedFcn = createCallbackFcn(app, @SpatialFreqValueChanged, true);
            app.SpatialFreq.Value = '0.08';
            app.SpatialFreq_Label = uilabel(app.VisualStim1Panel);
            app.SpatialFreq_Label.Text = 'cpd';
            app.LoomingSpd = uidropdown(app.VisualStim1Panel);
            app.LoomingSpd.Items = {'5', '10', '20', '40', '80'};
            app.LoomingSpd.ValueChangedFcn = createCallbackFcn(app, @LoomingSpdValueChanged, true);
            app.LoomingSpd.Value = '5';
            app.BarOrientation_Label = uilabel(app.VisualStim1Panel);
            app.BarOrientation_Label.Text = 'deg (0: => Vertical)';
            app.LoomingMax = uieditfield(app.VisualStim1Panel, 'numeric');
            app.LoomingMax.ValueChangedFcn = createCallbackFcn(app, @LoomingMaxValueChanged, true);
            app.LoomingMax.Value = 40;
            app.LoomingSpd_Label = uilabel(app.VisualStim1Panel);
            app.LoomingSpd_Label.Text = 'deg/sec';
            app.NumImages = uieditfield(app.VisualStim1Panel, 'numeric');
            app.NumImages.ValueChangedFcn = createCallbackFcn(app, @NumImagesValueChanged, true);
            app.NumImages.Value = 256;
            app.LoomingMax_Label = uilabel(app.VisualStim1Panel);
            app.LoomingMax_Label.Text = 'deg';
            app.DotDensity = uieditfield(app.VisualStim1Panel, 'numeric');
            app.DotDensity.ValueChangedFcn = createCallbackFcn(app, @DotDensityValueChanged, true);
            app.DotDensity.Value = 30;
            app.MoveSpd = uidropdown(app.VisualStim1Panel);
            app.MoveSpd.Items = {'5', '10', '20', '40'};
            app.MoveSpd.ValueChangedFcn = createCallbackFcn(app, @MoveSpdValueChanged, true);
            app.MoveSpd.Value = '10';
            app.DotDensity_Label = uilabel(app.VisualStim1Panel);
            app.DotDensity_Label.Text = '%';
            app.ConcentricDirection = uidropdown(app.VisualStim1Panel);
            app.ConcentricDirection.Items = {'0', '45', '90', '135', '180', '225', '270', '315', 'Ord8', 'Rand8', 'Rand12', 'Rand16'};
            app.ConcentricDirection.ValueChangedFcn = createCallbackFcn(app, @ConcentricDirectionValueChanged, true);
            app.ConcentricDirection.Value = '0';
            app.ConcentricDirection_Label = uilabel(app.VisualStim1Panel);
            app.ConcentricDirection_Label.Text = 'deg (0: => Rightward)';
            app.BarOrientation = uidropdown(app.VisualStim1Panel);
            app.BarOrientation.Items = {'0', '30', '60', '90', '-30', '-60', 'Ord6', 'Rand6', 'Rand12'};
            app.BarOrientation.Value = '0';
            app.ISIEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.ISIEditFieldLabel.Text = 'ISI';
            app.BarHeight = uilabel(app.VisualStim1Panel);
            app.BarHeight.Visible = 'off';
            app.BarHeight.Text = 'Heigth: 65 deg';
            app.MoovingSpdLabel_2 = uilabel(app.VisualStim1Panel);
            app.MoovingSpdLabel_2.Text = 'Coherence';
            app.Coherence = uidropdown(app.VisualStim1Panel);
            app.Coherence.Items = {'Random, 50%', '100%'};
            app.Coherence.Value = 'Random, 50%';
            app.Coherence_Mode = uibutton(app.VisualStim1Panel, 'state');
            app.Coherence_Mode.ValueChangedFcn = createCallbackFcn(app, @Coherence_ModeValueChanged, true);
            app.Coherence_Mode.Text = 'Easy';
            app.Distance = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Distance.ValueChangedFcn = createCallbackFcn(app, @DistanceValueChanged, true);
            app.Distance.Value = 15;
            app.DistanceLabel = uilabel(app.VisualStim1Panel);
            app.DistanceLabel.Text = 'Distance';
            app.Divide = uieditfield(app.VisualStim1Panel, 'numeric');
            app.Divide.ValueChangedFcn = createCallbackFcn(app, @DivideValueChanged, true);
            app.Divide.Value = 5;
            app.DivideLabel = uilabel(app.VisualStim1Panel);
            app.DivideLabel.Text = 'Divide';
            app.CheckerDivDropDown = uidropdown(app.VisualStim1Panel);
            app.CheckerDivDropDown.Items = {'5', '16', '32'};
            app.CheckerDivDropDown.ValueChangedFcn = createCallbackFcn(app, @CheckerDivDropDownValueChanged, true);
            app.CheckerDivDropDown.Value = '5';
            app.CheckerDivLabel = uilabel(app.VisualStim1Panel);
            app.CheckerDivLabel.Text = 'Checker Div';
            app.LuminanceEditFieldLabel = uilabel(app.VisualStim1Panel);
            app.LuminanceEditFieldLabel.Text = 'Luminance';
            app.LuminanceEditField = uieditfield(app.VisualStim1Panel, 'numeric');
            app.LuminanceEditField.Limits = [0 255];
            app.LuminanceEditField.ValueDisplayFormat = '%.0f';
            app.LuminanceEditField.ValueChangedFcn = createCallbackFcn(app, @LuminanceEditFieldValueChanged, true);
            app.LuminanceEditField.Value = 255;
            app.CheckerboardButton = uibutton(app.VisualStim1Panel, 'state');
            app.CheckerboardButton.ValueChangedFcn = createCallbackFcn(app, @CheckerboardButtonValueChanged, true);
            app.CheckerboardButton.Text = 'Checkerboard';
            app.FlashButton = uibutton(app.VisualStim1Panel, 'state');
            app.FlashButton.ValueChangedFcn = createCallbackFcn(app, @FlashButtonValueChanged, true);
            app.FlashButton.Text = 'Flash';
            app.StiminfoTextArea = uitextarea(app.BitPlayer_PTBUIFigure);
            app.StiminfoTextArea.Value = {'Pattern:'; 'Position:'; 'Info1'; 'Info2'; 'Info3'; 'Info4'};
            app.TTLfromDAQLamp = uilamp(app.BitPlayer_PTBUIFigure);
            app.OpenPTBButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.OpenPTBButton.ValueChangedFcn = createCallbackFcn(app, @OpenPTBButtonValueChanged, true);
            app.OpenPTBButton.Enable = 'off';
            app.OpenPTBButton.Text = 'Open PTB';
            app.ClosePTBButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.ClosePTBButton.ValueChangedFcn = createCallbackFcn(app, @ClosePTBButtonValueChanged, true);
            app.ClosePTBButton.Enable = 'off';
            app.ClosePTBButton.Text = 'Close PTB';
            app.EXITButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.StandAloneModeButton = uibutton(app.BitPlayer_PTBUIFigure, 'state');
            app.StandAloneModeButton.ValueChangedFcn = createCallbackFcn(app, @StandAloneModeButtonValueChanged, true);
            app.StandAloneModeButton.Text = 'Stand Alone Mode';
            app.DebugButton = uibutton(app.BitPlayer_PTBUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.StiminfoTextAreaLabel = uilabel(app.BitPlayer_PTBUIFigure);
            app.StiminfoTextAreaLabel.Text = 'Stim info';
            % Show the figure after all components are created
            app.BitPlayer_PTBUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_ptb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BitPlayer_PTBUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_PTBUIFigure)
        end
    end
end