classdef TransformFinePosition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SetGridLabel                   matlab.ui.control.Label
        SetGrid                        matlab.ui.control.NumericEditField
        GridLabel                      matlab.ui.control.Label
        TransformButton                matlab.ui.control.Button
        FinemapGridLabel               matlab.ui.control.Label
        FinemapPosition                matlab.ui.control.NumericEditField
        FinemapPositionEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Main %Main app object
    end
    
    properties (Access = public)
        sobj
        xy_finemap
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property
            app.Main = mainapp;
            s = mainapp.sobj;

            divnum = num2str(app.Main.Divide.Value);
            app.FinemapGridLabel.Text = ...
                [ '<- ' divnum ' x ' divnum ' in Fine map' ];

            if ~isfield(s, 'CenterPos_list')
                %Get FinMap Positions
                FineMapArea_deg = [0, 0, s.Distance, s.Distance];
                FineMapArea = Deg2Pix(FineMapArea_deg, s.MonitorDist, s.Pixelpitch);

                %Define center of the subarea (fix pos in DivNum^2 matrix)
                Pos_list = Get_StimCenter_in_matrix(s.RECT, s.DivNum);
                C = Pos_list(s.FixPos, :);

                %Corner position in pix
                AREA = CenterRectOnPointd(FineMapArea, C(1), C(2));

                %Define CenterPos_list for fine mapping
                s.CenterPos_list = Get_StimCenter_in_matrix(AREA, s.Div_grid);

            end
            
            app.xy_finemap = s.CenterPos_list(1, :);

            app.sobj = s;

        end

        % Value changed function: SetGrid
        function SetGridValueChanged(app, event)
            value = num2str(app.SetGrid.Value);
            app.GridLabel.Text = ['x ' value];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Pass variables to main app
            
            app.Main.sobj = app.sobj;
            app.Main.GetFinePos.Enable= 'on';

            delete(app)
            
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            % Get fullscreen grid position
            s = app.sobj;
            xy = Get_StimCenter_in_matrix(s.RECT, app.SetGrid.Value);

            % Finde nearest grid position;
            app.sobj.DivNum = app.SetGrid.Value;              % 追加: DivNumも同期
            app.sobj.FixPos = knnsearch(xy, app.xy_finemap);

            app.Main.MonitorDiv.Value = app.SetGrid.Value;
            app.Main.FixedPos.Value = app.sobj.FixPos;

            % 追加: メイン側のラベル表示もMonitorDivValueChanged/FixedPosValueChangedと同じ内容に揃える
            app.Main.MonitorDiv_Label.Text = ...
                [num2str(app.SetGrid.Value), 'x', num2str(app.SetGrid.Value), ' Matrix'];
            app.Main.FixedPos_Label.Text = ...
                ['in ', num2str(app.SetGrid.Value), 'x', num2str(app.SetGrid.Value), ' Matrix'];


        end

        % Value changed function: FinemapPosition
        function FinemapPositionValueChanged(app, event)
            value = app.FinemapPosition.Value;
            % FineMap grid position in the monitor
            app.xy_finemap = app.sobj.CenterPos_list(value, :);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FinemapPositionEditFieldLabel = uilabel(app.UIFigure);
            app.FinemapPositionEditFieldLabel.Text = 'Finemap Position:';
            app.FinemapPosition = uieditfield(app.UIFigure, 'numeric');
            app.FinemapPosition.ValueChangedFcn = createCallbackFcn(app, @FinemapPositionValueChanged, true);
            app.FinemapPosition.Value = 1;
            app.FinemapGridLabel = uilabel(app.UIFigure);
            app.FinemapGridLabel.Text = '<-  *** x ***  in Fine map';
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.GridLabel = uilabel(app.UIFigure);
            app.GridLabel.Text = 'x 200';
            app.SetGrid = uieditfield(app.UIFigure, 'numeric');
            app.SetGrid.ValueChangedFcn = createCallbackFcn(app, @SetGridValueChanged, true);
            app.SetGrid.Value = 200;
            app.SetGridLabel = uilabel(app.UIFigure);
            app.SetGridLabel.Text = 'Set Grid: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransformFinePosition(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end